classdef Tracer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        ImportMenu                    matlab.ui.container.Menu
        ObjectMenu                    matlab.ui.container.Menu
        VolumeMenu                    matlab.ui.container.Menu
        AtommodelMenu                 matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        XYZfilexyzMenu                matlab.ui.container.Menu
        MATfilematMenu                matlab.ui.container.Menu
        RAWfilerawMenu                matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        ViewMenu                      matlab.ui.container.Menu
        ExportMenu                    matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        ExpandYourHorizonLabel        matlab.ui.control.Label
        SideView1TabGroup             matlab.ui.container.TabGroup
        Sideview1Tab                  matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        SideView1Axes                 matlab.ui.control.UIAxes
        FinetraceTab_1                matlab.ui.container.Tab
        FineTrace1_up_Axes            matlab.ui.control.UIAxes
        FineTrace1_middle_Axes        matlab.ui.control.UIAxes
        FineTrace1_down_Axes          matlab.ui.control.UIAxes
        SideView2TabGroup             matlab.ui.container.TabGroup
        Sideview2Tab                  matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        SideView2Axes                 matlab.ui.control.UIAxes
        FinetraceTab_2                matlab.ui.container.Tab
        FineTrace2_up_Axes            matlab.ui.control.UIAxes
        FineTrace2_middle_Axes        matlab.ui.control.UIAxes
        FineTrace2_down_Axes          matlab.ui.control.UIAxes
        Image                         matlab.ui.control.Image
        TabGroup                      matlab.ui.container.TabGroup
        BasicTab                      matlab.ui.container.Tab
        FigureinfoPanel               matlab.ui.container.Panel
        LightOnButton                 matlab.ui.control.Button
        Slider_Isovalue               matlab.ui.control.Slider
        DisplaymodeDropDown           matlab.ui.control.DropDown
        DisplaymodeDropDownLabel      matlab.ui.control.Label
        RectanglerangeXEditField_2    matlab.ui.control.NumericEditField
        RectanglerangeYEditField_2    matlab.ui.control.NumericEditField
        DrawButton                    matlab.ui.control.Button
        RectanglerangeXEditField      matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        RectanglerangeYEditField      matlab.ui.control.NumericEditField
        RectanglerangeEditFieldLabel  matlab.ui.control.Label
        MasktypeDropDown              matlab.ui.control.DropDown
        MasktypeDropDownLabel         matlab.ui.control.Label
        DrawmaskButton                matlab.ui.control.Button
        ViewdirectionDropDown         matlab.ui.control.DropDown
        ViewdirectionDropDownLabel    matlab.ui.control.Label
        ColormapDropDown              matlab.ui.control.DropDown
        ColormapDropDownLabel         matlab.ui.control.Label
        SlicecontrolPanel             matlab.ui.container.Panel
        ThicknessSpinner              matlab.ui.control.Spinner
        ThicknessSpinnerLabel         matlab.ui.control.Label
        ChangestepSpinner             matlab.ui.control.Spinner
        ChangestepSpinnerLabel        matlab.ui.control.Label
        LayerresetButton              matlab.ui.control.Button
        LayerdownButton               matlab.ui.control.Button
        LayerupButton                 matlab.ui.control.Button
        LayerToEditField              matlab.ui.control.NumericEditField
        toEditFieldLabel              matlab.ui.control.Label
        LayerFromEditField            matlab.ui.control.NumericEditField
        LayerfromEditFieldLabel       matlab.ui.control.Label
        TracePanel                    matlab.ui.container.Panel
        UndoButton                    matlab.ui.control.Button
        RedoButton                    matlab.ui.control.Button
        DeleteRestoreButton           matlab.ui.control.Button
        StarttracingButton            matlab.ui.control.StateButton
        AddButton                     matlab.ui.control.Button
        ShowatomButton                matlab.ui.control.StateButton
        RotationsettingsPanel         matlab.ui.container.Panel
        ApplyrotationButton           matlab.ui.control.Button
        ChangestepSpinner_2           matlab.ui.control.Spinner
        ChangestepSpinner_2Label      matlab.ui.control.Label
        Spinner_Psi                   matlab.ui.control.Spinner
        Slider_Psi                    matlab.ui.control.Slider
        Label_2                       matlab.ui.control.Label
        Spinner_Theta                 matlab.ui.control.Spinner
        Slider_Theta                  matlab.ui.control.Slider
        Label                         matlab.ui.control.Label
        Spinner_Phi                   matlab.ui.control.Spinner
        Slider_Phi                    matlab.ui.control.Slider
        SliderLabel                   matlab.ui.control.Label
        DispTab                       matlab.ui.container.Tab
        DrawatomPanel                 matlab.ui.container.Panel
        AtomsizeEditField             matlab.ui.control.NumericEditField
        AtomsizeEditFieldLabel        matlab.ui.control.Label
        AtomColorButton               matlab.ui.control.Button
        AtomListBox                   matlab.ui.control.ListBox
        ZoominPanel                   matlab.ui.container.Panel
        ResetZoomROIButton            matlab.ui.control.Button
        DrawZoomROIButton             matlab.ui.control.Button
        IsosurfaceinfoPanel           matlab.ui.container.Panel
        IsoColorDisplayLabel          matlab.ui.control.Label
        IsoColorButton                matlab.ui.control.Button
        IsoAlphaSlider                matlab.ui.control.Slider
        AlphaSliderLabel              matlab.ui.control.Label
        MessageBoxPanel               matlab.ui.container.Panel
        MessageBoxTextArea            matlab.ui.control.TextArea
        MainImageAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Phi=0; % One of the Euler angle sets
        Theta=0; % One of the Euler angle sets
        Psi=0; % One of the Euler angle sets
        MessageBoxText % Text of MessageBoxapp.Phi
        OriginalVolume = rand(200,200,200) % The origin volume
        uint8Volume % The light version of OriginVolume
        Projection % Projection generated by CurrentSlice.
        Imsize % Projection image size
        Volsize % Volume size
        VolumeRotated % The rotate volume to generate slice.
        CurrentSlice % The slice of volume to generate Projection for view.
        roi_position % Position of region of interest
        zoom_position % Position of zoom in area
        ROIVolume % Volume selected by roi
        CameraView % The view orientation of main axes.
        Model % Atom model, with Model.AtomPositions, Model.AtomType and Model.AtomTracingAttributes
        ModelRotated % Model after rotate
        CurrentAtomMask % Mask for atom model to display
        maxV % The max value of volume
        originAtomNum % Original atom number
        DisplayMode % Isosurface 1; Projection 0
        
        % The settings for draw atoms
        AtomTypeList % Atom color list
        typemask = cell(1,6) % find atom result
        AtomColorList = {"red","blue","green","cyan","magenta","yellow"}
        
        % The object in figure
        roi_frame_plot % The frame display of roi
        main_projection_im % image of main projection
        main_projection_p % patch of main projection
        main_projection_atom_scatter = cell(1,6) % Original atoms
        main_projection_add_scatter = cell(1,6) % Add atoms
        main_projection_delete_scatter = cell(1,6) % Delete atoms
        side_view_1_im % image of sideview 1
        side_view_2_im % image of sideview 2
        sidescatter1_im = cell(1,6) % Side scatter 1
        sidescatter1_add_im = cell(1,6) % Side scatter 1 add
        sidescatter1_delete_im = cell(1,6) % Side scatter 1 delete
        sidescatter2_im = cell(1,6) % Side scatter 2
        sidescatter2_add_im = cell(1,6) % Side scatter 2 add
        sidescatter2_delete_im = cell(1,6) % Side scatter 2 delete
        
        % For tracing
        save_changed = 1
        ActionsNum = 0 % Total actions
        ActionsList = zeros(2,10000) % The list for tracing actions, contains trace_state stream
        start_trace = 0 % start tracing
        trace_state = 0 % Trace state, 1 for add, 2 for delete, 3 for redo, 4 for undo, 0 for none
        OriginalAtomTracingAttributes
        % Add part
        AddAtomNum = 0
        temp_add_coordinates = ones(1,3)*-1
        temp_add_coordinates_sc % Timely show the atom needs to be add.
        temp_delete_coordinates = ones(1,3)*-1
        
        % Images for fine trace
        finetrace_state = 0
        finetrace1_up_im
        finetrace1_middle_im
        finetrace1_down_im
        finetrace2_up_im
        finetrace2_middle_im
        finetrace2_down_im
        finish_fine_trace = 0
        
        % Delete part
        DeleteAtomNum = 0 % Number of deleted atoms
        delete_ind_list % List of delete atoms' indices
        
    end
    
    methods (Access = private)
        
        function loadVolume(app,filepath)
            % Initiate
            app.OriginalVolume = [];
            app.Volsize = [];
            app.maxV = [];
            app.Model = [];
            app.ModelRotated = [];
            
            app.DisplaymodeDropDown.Enable = 1;
            S = load(filepath);
            fields = fieldnames(S);
            % V_obj = getfield(S,fields{1});
            V_obj = S.(fields{1});
            if isstruct(V_obj)
                if isfield(V_obj,"Volume")
                    app.OriginalVolume = V_obj.Volume;
                    app.Volsize = size(app.OriginalVolume);
                    app.maxV = max(app.OriginalVolume(:));
                end
                if isfield(V_obj,"AtomPositions")
                    V_obj.AtomPositions = V_obj.AtomPositions + (app.Volsize'+1)/2;
                    app.Model.AtomPositions = V_obj.AtomPositions;
                    app.originAtomNum = size(app.Model.AtomPositions,2);
                    app.CurrentAtomMask = ones(1, app.originAtomNum);
                    
                end
                if isfield(V_obj,"AtomType")
                    app.AtomListBox.Enable = 1;
                    app.AtomColorButton.Enable = 1;
                    app.AtomsizeEditField.Enable = 1;
                    app.Model.AtomType = V_obj.AtomType;
                    app.AtomTypeList = unique(app.Model.AtomType, 'sorted');
                    
                    
                end
                if isfield(V_obj,"AtomTracingAttributes")
                    app.Model.AtomTracingAttributes = V_obj.AtomTracingAttributes;
                    app.DeleteAtomNum = sum(app.Model.AtomTracingAttributes == 0);
                    app.OriginalAtomTracingAttributes = app.Model.AtomTracingAttributes;
                end
                app.ModelRotated = app.Model;
            else
                if isfield(S,"Volume")
                    app.OriginalVolume = S.Volume;
                end
                if isfield(S,"reconstruction")
                    app.OriginalVolume = S.Volume;
                end
            end
        end
        
        function init(app)
            % Reset axes
            if ~isempty(app.roi_frame_plot)
                app.roi_frame_plot.delete();
                app.roi_frame_plot = [];
            end
            if ~isempty(app.main_projection_im)
                app.main_projection_im.delete();
                app.main_projection_im = [];
            end
            if ~isempty(app.main_projection_p)
                app.main_projection_p.delete();
                app.main_projection_p = [];
            end

            for type = 1:length(app.main_projection_atom_scatter)
                if ~isempty(app.main_projection_atom_scatter{type})
                    app.main_projection_atom_scatter{type}.delete();
                    app.main_projection_atom_scatter{type} = [];
                end
            end
            
            for type = 1:length(app.main_projection_add_scatter)
                if ~isempty(app.main_projection_add_scatter{type})
                    app.main_projection_add_scatter{type}.delete();
                    app.main_projection_add_scatter{type} = [];
                end
            end
            
            for type = 1:length(app.main_projection_delete_scatter)
                if ~isempty(app.main_projection_delete_scatter{type})
                    app.main_projection_delete_scatter{type}.delete();
                    app.main_projection_delete_scatter{type} = [];
                end
            end

            if ~isempty(app.side_view_1_im)
                app.side_view_1_im.delete();
                app.side_view_1_im = [];
            end
            if ~isempty(app.side_view_2_im)
                app.side_view_2_im.delete();
                app.side_view_2_im = [];
            end
            
            for type = 1:length(app.sidescatter1_im)
                if ~isempty(app.sidescatter1_im{type})
                    app.sidescatter1_im{type}.delete()
                    app.sidescatter1_im{type} = [];
                end
            end
            
            for type = 1:length(app.sidescatter1_add_im)
                if ~isempty(app.sidescatter1_add_im{type})
                    app.sidescatter1_add_im{type}.delete()
                    app.sidescatter1_add_im{type} = [];
                end
            end
            for type = 1:length(app.sidescatter1_delete_im)
                if ~isempty(app.sidescatter1_delete_im{type})
                    app.sidescatter1_delete_im{type}.delete()
                    app.sidescatter1_delete_im{type} = [];
                end
            end
            for type = 1:length(app.sidescatter2_im)
                if ~isempty(app.sidescatter2_im{type})
                    app.sidescatter2_im{type}.delete()
                    app.sidescatter2_im{type} = [];
                end
            end
            for type = 1:length(app.sidescatter2_add_im)
                if ~isempty(app.sidescatter2_add_im{type})
                    app.sidescatter2_add_im{type}.delete()
                    app.sidescatter2_add_im{type} = [];
                end
            end
            for type = 1:length(app.sidescatter2_delete_im)
                if ~isempty(app.sidescatter2_delete_im{type})
                    app.sidescatter2_delete_im{type}.delete()
                    app.sidescatter2_delete_im{type} = [];
                end
            end
            
            % Reset figure info
            app.DisplaymodeDropDown.Value = "Projection";
            app.DisplayMode = 0;
            app.ViewdirectionDropDown.Value = 'Z';
            app.Slider_Isovalue.Value = 0.5;
            app.Slider_Isovalue.Enable = 0;
            app.LightOnButton.Text = "Light On";
            app.LightOnButton.Enable = 0;
            
            % Reset trace
            app.StarttracingButton.Value = 0;
            app.AddButton.Enable = 0;
            app.DeleteRestoreButton.Enable = 0;
            app.RedoButton.Enable = 0;
            app.UndoButton.Enable = 0;
            
            % Reset Slice control
            app.ThicknessSpinner.Value = size(app.OriginalVolume,3);
            app.LayerFromEditField.Value = 1;
            app.LayerToEditField.Value = size(app.OriginalVolume,3);
            app.ChangestepSpinner.Value = 1;
            
            % Reset Isosurface Info
            app.IsoAlphaSlider.Enable = 0;
            app.IsoAlphaSlider.Value = 0.5;
            
            % Reset Draw atom
            if ~isempty(app.Model)
                temp_item_list = [];
                temp_item_data_list = [];
                for type = app.AtomTypeList
                    app.typemask{type} = (app.Model.AtomType==type);
                    temp_item = strcat("Atom",num2str(type));
                    temp_item_list = [temp_item_list,temp_item];
                    temp_item_data_list = [temp_item_data_list, type];
                    
                end
                app.AtomListBox.Items = temp_item_list;
                app.AtomListBox.Value = temp_item_list(1);
            end
            
            % Reload Volume
            app.VolumeRotated = app.OriginalVolume;
            app.uint8Volume = uint8(app.VolumeRotated / max(app.VolumeRotated(:)) * 256);
            app.Volsize = size(app.OriginalVolume);
            
            % Initiate the value of layer edit field
            app.LayerFromEditField.Value = 1;
            app.LayerToEditField.Value = app.Volsize(1);
            
            % Initiate the RectangleRangeEditField limits
            app.RectanglerangeXEditField.Limits = [1,app.Volsize(1)-1];
            app.RectanglerangeXEditField_2.Limits = [2,app.Volsize(1)];
            app.RectanglerangeYEditField.Limits = [1,app.Volsize(2)-1];
            app.RectanglerangeYEditField_2.Limits = [2,app.Volsize(2)];
            
            % Initiate CurrentSlice;
            app.CurrentSlice = app.VolumeRotated;
            
            % Update main ax
            update_mainax(app);
            app.CameraView = app.MainImageAxes.View;
            
        end
        
        function update_mainax(app,varargin)
            if nargin > 1
                mode = varargin{1}; % mode 1: update all; mode 2: update imagesc; mode 3: update scatter3;
            else
                mode = 1;
            end
            
            % update scatter
            if ~isempty(app.Model)
                if mode == 1 || mode == 3
                    app.update_mainax_sc();
                end
            end
            
            % update figure
            switch app.DisplayMode
                case 1 % Isosurface mode
                    if mode == 1 || mode == 2
                        app.CameraView = app.MainImageAxes.View; % Record the original view
                        % xmin and ymin contains the first point of roi
                        xmin = 1;
                        ymin = 1;
                        % This part is Isosurface mode
                        if ~isempty(app.main_projection_im)
                            app.main_projection_im.delete();
                            app.main_projection_im = [];
                        end
                        if ~isempty(app.main_projection_p)
                            app.main_projection_p.delete();
                            app.main_projection_p = [];
                        end
                        hold(app.MainImageAxes, "off");
                        slicesize = size(app.CurrentSlice);
                        [X,Y,Z] = meshgrid(1:slicesize(2),1:slicesize(1),1:slicesize(3));
                        if ~isempty(app.zoom_position)
                            position = round(app.zoom_position);
                            % [xmin, ymin, width, height]
                            ymin = position(1);
                            xmin = position(2);
                            X = (X+ymin-1);
                            Y = (Y+xmin-1);
                        end
                        Z = (Z-1+app.LayerFromEditField.Value);
                        fv = isosurface(X,Y,Z,app.CurrentSlice,max(app.CurrentSlice(:))*app.Slider_Isovalue.Value);
                        app.main_projection_p = patch(app.MainImageAxes, fv);
                        app.main_projection_p.FaceColor = app.IsoColorDisplayLabel.BackgroundColor;
                        app.main_projection_p.EdgeColor = 'none';
                        app.main_projection_p.FaceAlpha = app.IsoAlphaSlider.Value;
                        daspect(app.MainImageAxes,[1 1 1]);
                        view(app.MainImageAxes,3);
                        axis(app.MainImageAxes,"image");
                        xlim(app.MainImageAxes, [ymin,ymin+slicesize(2)-1]);
                        ylim(app.MainImageAxes, [xmin,xmin+slicesize(1)-1]);
                        zlim(app.MainImageAxes, [app.LayerFromEditField.Value,app.LayerToEditField.Value]);
                        if strcmp(app.LightOnButton.Text,"Light On")
                            camlight(app.MainImageAxes,'headlight');
                            lighting(app.MainImageAxes,"flat");
                        else
                            camlight(app.MainImageAxes,'headlight');
                            lighting(app.MainImageAxes,"none");
                        end
                        app.MainImageAxes.View = app.CameraView;
                    end
                
                case 0 % Projection mode
                    app.MainImageAxes.View = [0, 90];
                    app.Projection = sum(app.CurrentSlice, 3);
%                     if strcmp(app.ViewdirectionDropDown.Value, "Z")
%                         app.Projection = sum(app.CurrentSlice, 3);
%                     elseif strcmp(app.ViewdirectionDropDown.Value, "Y")
%                         app.Projection = squeeze(sum(app.CurrentSlice, 2));
%                     elseif strcmp(app.ViewdirectionDropDown.Value, "X")
%                         app.Projection = squeeze(sum(app.CurrentSlice, 1));
%                     end
                    app.Imsize = size(app.Projection);
                    % This part is Projection mode
                    % Update projection in app.MainImageAxes
                    if ~isempty(app.main_projection_im)
                        app.main_projection_im.delete();
                        app.main_projection_im = [];
                    end
                    if ~isempty(app.main_projection_p)
                        app.main_projection_p.delete();
                        app.main_projection_p = [];
                    end
                    app.main_projection_im = imagesc(app.MainImageAxes, app.Projection);
                    xlim(app.MainImageAxes, [0,app.Imsize(1)]);
                    ylim(app.MainImageAxes, [0,app.Imsize(2)]);
                    colormap(app.MainImageAxes, app.ColormapDropDown.Value);
                    axis(app.MainImageAxes, 'image');
                    hold(app.MainImageAxes, "on");
                    
                    % Draw side view if roi exist.
                    if ~isempty(app.roi_position)
                        app.DrawSideView();
                        app.DrawRoiFrame();
                    end
                    
                    hold(app.MainImageAxes,"off");
                    
                    if ~isempty(app.ModelRotated) && (mode == 1 || mode == 3)
                        app.update_mainax_sc();
                    end
            end
            
            
        end
        
        function update_mainax_sc(app)
            switch app.DisplayMode
                %===== isosurface mode, show 3d scatter =====%
                case 1
                    % Draw
                    if ~isempty(app.Model)
                        hold(app.MainImageAxes,"on");
                        
                        % Delete previous scatter
                        for type = app.AtomTypeList
                            if ~isempty(app.main_projection_atom_scatter{type})
                                app.main_projection_atom_scatter{type}.delete();
                                app.main_projection_atom_scatter{type} = [];
                            end
                        end
                        
                        for type = app.AtomTypeList
                            if ~isempty(app.main_projection_add_scatter{type})
                                app.main_projection_add_scatter{type}.delete();
                                app.main_projection_add_scatter{type} = [];
                            end
                        end
                        
                        for type = app.AtomTypeList
                            if ~isempty(app.main_projection_delete_scatter{type})
                                app.main_projection_delete_scatter{type}.delete();
                                app.main_projection_delete_scatter{type} = [];
                            end
                        end
                        
                        % Draw new scatter
                        for type = app.AtomTypeList
                            temp_type_mask = app.Model.AtomType==type & app.CurrentAtomMask;
                            % Draw original atoms
                            temp_origin_mask = temp_type_mask & app.Model.AtomTracingAttributes==3;
                            app.main_projection_atom_scatter{type} = scatter3(app.MainImageAxes,app.Model.AtomPositions(2,temp_origin_mask),...
                                app.Model.AtomPositions(1,temp_origin_mask),app.Model.AtomPositions(3,temp_origin_mask),...
                                'filled','MarkerEdgeColor',"none","MarkerFaceColor",app.AtomColorList{type},'SizeData',app.AtomsizeEditField.Value); 
                            
                            % Draw add atoms
                            temp_add_mask = temp_type_mask & app.Model.AtomTracingAttributes==1;
                            app.main_projection_add_scatter{type} = scatter3(app.MainImageAxes,app.Model.AtomPositions(2,temp_add_mask),...
                                app.Model.AtomPositions(1,temp_add_mask),app.Model.AtomPositions(3,temp_add_mask),'+',...
                                'MarkerEdgeColor',[1,0.5,0],"LineWidth",2,'SizeData',app.AtomsizeEditField.Value*2);
                            
                            % Draw delete atoms
                            temp_add_mask = temp_type_mask & app.Model.AtomTracingAttributes==0;
                            app.main_projection_delete_scatter{type} = scatter3(app.MainImageAxes,app.Model.AtomPositions(2,temp_add_mask),...
                                app.Model.AtomPositions(1,temp_add_mask),app.Model.AtomPositions(3,temp_add_mask),'o',...
                                'MarkerEdgeColor',app.AtomColorList{type},"LineWidth",1,'SizeData',app.AtomsizeEditField.Value);
                            
                        end
                        hold(app.MainImageAxes,"off");
                    end
                
                %===== projection mode, show 2d scatter =====%
                case 0
                    % Delete previous scatter
                    hold(app.MainImageAxes,"on");
                    for type = app.AtomTypeList
                        if ~isempty(app.main_projection_atom_scatter{type})
                            app.main_projection_atom_scatter{type}.delete();
                            app.main_projection_atom_scatter{type} = [];
                        end
                        if ~isempty(app.main_projection_add_scatter{type})
                            app.main_projection_add_scatter{type}.delete();
                            app.main_projection_add_scatter{type} = [];
                        end
                        if ~isempty(app.main_projection_delete_scatter{type})
                            app.main_projection_delete_scatter{type}.delete();
                            app.main_projection_delete_scatter{type} = [];
                        end
                    end
                    % Draw
                    for type = app.AtomTypeList
                        temp_type_mask = app.ModelRotated.AtomType==type & app.CurrentAtomMask;
                        % Draw original atoms
                        temp_origin_mask = temp_type_mask & app.ModelRotated.AtomTracingAttributes==3;
                        temp_s = scatter(app.MainImageAxes,app.ModelRotated.AtomPositions(2,temp_origin_mask),...
                            app.ModelRotated.AtomPositions(1,temp_origin_mask),...
                            'filled','MarkerEdgeColor',"none","MarkerFaceColor",app.AtomColorList{type},...
                            "SizeData",app.AtomsizeEditField.Value); 
%                         switch app.ViewdirectionDropDown.Value
%                             case 'Z'
%                                 temp_s = scatter(app.MainImageAxes,app.ModelRotated.AtomPositions(2,temp_origin_mask),...
%                                     app.ModelRotated.AtomPositions(1,temp_origin_mask),...
%                                     'filled','MarkerEdgeColor',"none","MarkerFaceColor",app.AtomColorList{type},...
%                                     "SizeData",app.AtomsizeEditField.Value); 
%                             case 'Y'
%                                 temp_s = scatter(app.MainImageAxes,app.ModelRotated.AtomPositions(3,temp_origin_mask),...
%                                     app.ModelRotated.AtomPositions(1,temp_origin_mask),...
%                                     'filled','MarkerEdgeColor',"none","MarkerFaceColor",app.AtomColorList{type},...
%                                     "SizeData",app.AtomsizeEditField.Value); 
%                             case 'X'
%                                 temp_s = scatter(app.MainImageAxes,app.ModelRotated.AtomPositions(3,temp_origin_mask),...
%                                     app.ModelRotated.AtomPositions(2,temp_origin_mask),...
%                                     'filled','MarkerEdgeColor',"none","MarkerFaceColor",app.AtomColorList{type},...
%                                     "SizeData",app.AtomsizeEditField.Value); 
%                         end
                        app.main_projection_atom_scatter{type} = temp_s;
                        
                        % Draw add atoms
                        temp_add_mask = temp_type_mask & app.Model.AtomTracingAttributes==1;
                        temp_s = scatter(app.MainImageAxes,app.ModelRotated.AtomPositions(2,temp_add_mask),...
                            app.ModelRotated.AtomPositions(1,temp_add_mask),...
                            '+','MarkerEdgeColor',[1,0.5,0],"LineWidth",2,'SizeData',app.AtomsizeEditField.Value*2); 
%                         switch app.ViewdirectionDropDown.Value
%                             case 'Z'
%                                 temp_s = scatter(app.MainImageAxes,app.ModelRotated.AtomPositions(2,temp_add_mask),...
%                                     app.ModelRotated.AtomPositions(1,temp_add_mask),...
%                                     '+','MarkerEdgeColor',[1,0.5,0],"LineWidth",2,'SizeData',app.AtomsizeEditField.Value*2); 
%                             case 'Y'
%                                 temp_s = scatter(app.MainImageAxes,app.ModelRotated.AtomPositions(3,temp_add_mask),...
%                                     app.ModelRotated.AtomPositions(1,temp_add_mask),...
%                                     '+','MarkerEdgeColor',[1,0.5,0],"LineWidth",2,'SizeData',app.AtomsizeEditField.Value*2); 
%                             case 'X'
%                                 temp_s = scatter(app.MainImageAxes,app.ModelRotated.AtomPositions(3,temp_add_mask),...
%                                     app.ModelRotated.AtomPositions(2,temp_add_mask),...
%                                     '+','MarkerEdgeColor',[1,0.5,0],"LineWidth",2,'SizeData',app.AtomsizeEditField.Value*2); 
%                         end
                        app.main_projection_add_scatter{type} = temp_s;
                        
                        % Draw delete atoms
                        temp_delete_mask = temp_type_mask & app.Model.AtomTracingAttributes==0;
                        temp_s = scatter(app.MainImageAxes,app.ModelRotated.AtomPositions(2,temp_delete_mask),...
                            app.ModelRotated.AtomPositions(1,temp_delete_mask),...
                            'o','MarkerEdgeColor',app.AtomColorList{type},"LineWidth",1,'SizeData',app.AtomsizeEditField.Value); 
%                         switch app.ViewdirectionDropDown.Value
%                             case 'Z'
%                                 temp_s = scatter(app.MainImageAxes,app.ModelRotated.AtomPositions(2,temp_delete_mask),...
%                                     app.ModelRotated.AtomPositions(1,temp_delete_mask),...
%                                     'o','MarkerEdgeColor',app.AtomColorList{type},"LineWidth",1,'SizeData',app.AtomsizeEditField.Value); 
%                             case 'Y'
%                                 temp_s = scatter(app.MainImageAxes,app.ModelRotated.AtomPositions(3,temp_delete_mask),...
%                                     app.ModelRotated.AtomPositions(1,temp_delete_mask),...
%                                     'o','MarkerEdgeColor',app.AtomColorList{type},"LineWidth",1,'SizeData',app.AtomsizeEditField.Value); 
%                             case 'X'
%                                 temp_s = scatter(app.MainImageAxes,app.ModelRotated.AtomPositions(3,temp_delete_mask),...
%                                     app.ModelRotated.AtomPositions(2,temp_delete_mask),...
%                                     'o','MarkerEdgeColor',app.AtomColorList{type},"LineWidth",1,'SizeData',app.AtomsizeEditField.Value); 
%                         end
                        app.main_projection_delete_scatter{type} = temp_s;
                    end
                    hold(app.MainImageAxes,"off");
                    
            end
        end
        
        function RotateVolume(app)
            tempVolume = imrotate3(app.OriginalVolume,app.Phi,[1,0,0],"linear","crop","FillValues",0);
            tempVolume = imrotate3(tempVolume,app.Theta,[0,1,0],"linear","crop","FillValues",0);
            app.VolumeRotated = imrotate3(tempVolume,app.Psi,[0,0,1],"linear","crop","FillValues",0);
            app.SliceVolume();
            
        end
        
        function RotateModel(app)
            radPhi = deg2rad(app.Phi);
            radTheta = deg2rad(app.Theta);
            radPsi = deg2rad(app.Psi);
            rotate_matrix = [
                cos(radPhi) 0 sin(radPhi);
                0 1 0;
                -sin(radPhi) 0 cos(radPhi)];
            rotate_matrix = [
                1 0 0;
                0 cos(radTheta) -sin(radTheta);
                0 sin(radTheta) cos(radTheta);] * rotate_matrix;
            rotate_matrix = [
                cos(radPsi) -sin(radPsi) 0;
                sin(radPsi) cos(radPsi) 0;
                0 0 1] * rotate_matrix;
            app.ModelRotated.AtomPositions = rotate_matrix*(app.Model.AtomPositions-(app.Volsize'+1)/2) + (app.Volsize'+1)/2;
        end
        
        function SliceVolume(app)
            % Isosurface mode
            if app.DisplayMode == 1
                if app.LayerFromEditField.Value >= 1 && app.LayerToEditField.Value <= app.Volsize(3) ...
                        && app.LayerFromEditField.Value <= app.LayerToEditField.Value
                    if isinteger(app.LayerFromEditField.Value) && isinteger(app.LayerToEditField.Value)
                        app.CurrentSlice = app.OriginalVolume(:,:,app.LayerFromEditField.Value:app.LayerToEditField.Value);
                    else
                        app.LayerFromEditField.Value = floor(app.LayerFromEditField.Value);
                        app.LayerToEditField.Value = ceil(app.LayerToEditField.Value);
                        app.CurrentSlice = app.OriginalVolume(:,:,app.LayerFromEditField.Value:app.LayerToEditField.Value);
                    end
                % Any other invalid input.
                elseif app.LayerFromEditField.Value > app.LayerToEditField.Value
                    if app.LayerFromEditField.Value >= 1 && app.LayerFromEditField.Value <= app.Volsize(3)
                        app.LayerToEditField.Value = floor(app.LayerFromEditField.Value);
                    elseif app.LayerToEditField.Value >= 1 && app.LayerToEditField.Value <= app.Volsize(3)
                        app.LayerFromEditField.Value = ceil(app.LayerToEditField.Value);
                    end
                    app.CurrentSlice = app.OriginalVolume(:,:,app.LayerFromEditField.Value:app.LayerToEditField.Value);
                elseif app.LayerFromEditField.Value < 1 || app.LayerToEditField.Value > app.Volsize(3)
                    if app.LayerFromEditField.Value < 1
                        app.LayerFromEditField.Value = 1;
                    end
                    if app.LayerToEditField.Value > app.Volsize(3)
                        app.LayerToEditField.Value = app.Volsize(3);
                    end
                    app.CurrentSlice = app.OriginalVolume(:,:,app.LayerFromEditField.Value:app.LayerToEditField.Value);
                end
                titleStr = strcat("Figure 1. Projection Slice: ",num2str(app.LayerFromEditField.Value),...
                    " - ", num2str(app.LayerToEditField.Value)," View direction:", app.ViewdirectionDropDown.Value,...
                    " Euler: ", num2str(app.Spinner_Phi.Value), "\_", num2str(app.Spinner_Theta.Value), "\_", num2str(app.Spinner_Psi.Value));
                app.MainImageAxes.Title.String = titleStr;
            
            % Projection mode
            elseif app.DisplayMode == 0
                if app.LayerFromEditField.Value >= 1 && app.LayerToEditField.Value <= app.Volsize(3) ...
                        && app.LayerFromEditField.Value <= app.LayerToEditField.Value
                    if isinteger(app.LayerFromEditField.Value) && isinteger(app.LayerToEditField.Value)
                        app.CurrentSlice = app.VolumeRotated(:,:,app.LayerFromEditField.Value:app.LayerToEditField.Value);
                    else
                        app.LayerFromEditField.Value = floor(app.LayerFromEditField.Value);
                        app.LayerToEditField.Value = ceil(app.LayerToEditField.Value);
                        app.CurrentSlice = app.VolumeRotated(:,:,app.LayerFromEditField.Value:app.LayerToEditField.Value);
                    end
                % Any other invalid input.
                elseif app.LayerFromEditField.Value > app.LayerToEditField.Value
                    if app.LayerFromEditField.Value >= 1 && app.LayerFromEditField.Value <= app.Volsize(3)
                        app.LayerToEditField.Value = floor(app.LayerFromEditField.Value);
                    elseif app.LayerToEditField.Value >= 1 && app.LayerToEditField.Value <= app.Volsize(3)
                        app.LayerFromEditField.Value = ceil(app.LayerToEditField.Value);
                    end
                    app.CurrentSlice = app.VolumeRotated(:,:,app.LayerFromEditField.Value:app.LayerToEditField.Value);
                elseif app.LayerFromEditField.Value < 1 || app.LayerToEditField.Value > app.Volsize(3)
                    if app.LayerFromEditField.Value < 1
                        app.LayerFromEditField.Value = 1;
                    end
                    if app.LayerToEditField.Value > app.Volsize(3)
                        app.LayerToEditField.Value = app.Volsize(3);
                    end
                    app.CurrentSlice = app.VolumeRotated(:,:,app.LayerFromEditField.Value:app.LayerToEditField.Value);
                end
                titleStr = strcat("Figure 1. Projection Slice: ",num2str(app.LayerFromEditField.Value),...
                    " - ", num2str(app.LayerToEditField.Value)," View direction:", app.ViewdirectionDropDown.Value,...
                    " Euler: ", num2str(app.Spinner_Phi.Value), "\_", num2str(app.Spinner_Theta.Value), "\_", num2str(app.Spinner_Psi.Value));
                app.MainImageAxes.Title.String = titleStr;
%                 switch app.ViewdirectionDropDown.Value
%                     case 'Z'
%                         if app.LayerFromEditField.Value >= 1 && app.LayerToEditField.Value <= app.Volsize(3) ...
%                                 && app.LayerFromEditField.Value <= app.LayerToEditField.Value
%                             if isinteger(app.LayerFromEditField.Value) && isinteger(app.LayerToEditField.Value)
%                                 app.CurrentSlice = app.VolumeRotated(:,:,app.LayerFromEditField.Value:app.LayerToEditField.Value);
%                             else
%                                 app.LayerFromEditField.Value = floor(app.LayerFromEditField.Value);
%                                 app.LayerToEditField.Value = ceil(app.LayerToEditField.Value);
%                                 app.CurrentSlice = app.VolumeRotated(:,:,app.LayerFromEditField.Value:app.LayerToEditField.Value);
%                             end
%                         % Any other invalid input.
%                         elseif app.LayerFromEditField.Value > app.LayerToEditField.Value
%                             if app.LayerFromEditField.Value >= 1 && app.LayerFromEditField.Value <= app.Volsize(3)
%                                 app.LayerToEditField.Value = floor(app.LayerFromEditField.Value);
%                             elseif app.LayerToEditField.Value >= 1 && app.LayerToEditField.Value <= app.Volsize(3)
%                                 app.LayerFromEditField.Value = ceil(app.LayerToEditField.Value);
%                             end
%                             app.CurrentSlice = app.VolumeRotated(:,:,app.LayerFromEditField.Value:app.LayerToEditField.Value);
%                         elseif app.LayerFromEditField.Value < 1 || app.LayerToEditField.Value > app.Volsize(3)
%                             if app.LayerFromEditField.Value < 1
%                                 app.LayerFromEditField.Value = 1;
%                             end
%                             if app.LayerToEditField.Value > app.Volsize(3)
%                                 app.LayerToEditField.Value = app.Volsize(3);
%                             end
%                             app.CurrentSlice = app.VolumeRotated(:,:,app.LayerFromEditField.Value:app.LayerToEditField.Value);
%                         end
%                         titleStr = strcat("Figure 1. Projection Slice: ",num2str(app.LayerFromEditField.Value),...
%                             " - ", num2str(app.LayerToEditField.Value)," View direction:", app.ViewdirectionDropDown.Value,...
%                             " Euler: ", num2str(app.Spinner_Phi.Value), "\_", num2str(app.Spinner_Theta.Value), "\_", num2str(app.Spinner_Psi.Value));
%                         app.MainImageAxes.Title.String = titleStr;
%                         
%                     % Slice from Y
%                     case 'Y'
%                         if app.LayerFromEditField.Value >= 1 && app.LayerToEditField.Value <= app.Volsize(2) ...
%                                 && app.LayerFromEditField.Value <= app.LayerToEditField.Value
%                             if isinteger(app.LayerFromEditField.Value) && isinteger(app.LayerToEditField.Value)
%                                 app.CurrentSlice = app.VolumeRotated(:,app.LayerFromEditField.Value:app.LayerToEditField.Value,:);
%                             else
%                                 app.LayerFromEditField.Value = floor(app.LayerFromEditField.Value);
%                                 app.LayerToEditField.Value = ceil(app.LayerToEditField.Value);
%                                 app.CurrentSlice = app.VolumeRotated(:,app.LayerFromEditField.Value:app.LayerToEditField.Value,:);
%                             end
%                         % Any other invalid input.
%                         elseif app.LayerFromEditField.Value > app.LayerToEditField.Value
%                             if app.LayerFromEditField.Value >= 1 && app.LayerFromEditField.Value <= app.Volsize(2)
%                                 app.LayerToEditField.Value = floor(app.LayerFromEditField.Value);
%                             elseif app.LayerToEditField.Value >= 1 && app.LayerToEditField.Value <= app.Volsize(2)
%                                 app.LayerFromEditField.Value = ceil(app.LayerToEditField.Value);
%                             end
%                             app.CurrentSlice = app.VolumeRotated(:,app.LayerFromEditField.Value:app.LayerToEditField.Value,:);
%                         elseif app.LayerFromEditField.Value < 1 || app.LayerToEditField.Value > app.Volsize(2)
%                             if app.LayerFromEditField.Value < 1
%                                 app.LayerFromEditField.Value = 1;
%                             end
%                             if app.LayerToEditField.Value > app.Volsize(2)
%                                 app.LayerToEditField.Value = app.Volsize(2);
%                             end
%                             app.CurrentSlice = app.VolumeRotated(:,app.LayerFromEditField.Value:app.LayerToEditField.Value,:);
%                         end
%                         titleStr = strcat("Figure 1. Projection Slice: ",num2str(app.LayerFromEditField.Value),...
%                             " - ", num2str(app.LayerToEditField.Value)," View direction:", app.ViewdirectionDropDown.Value,...
%                             " Euler: ", num2str(app.Spinner_Phi.Value), "\_", num2str(app.Spinner_Theta.Value), "\_", num2str(app.Spinner_Psi.Value));
%                         app.MainImageAxes.Title.String = titleStr;
%                     
%                     % Slice from X
%                     case 'X'
%                         if app.LayerFromEditField.Value >= 1 && app.LayerToEditField.Value <= app.Volsize(1) ...
%                                 && app.LayerFromEditField.Value <= app.LayerToEditField.Value
%                             if isinteger(app.LayerFromEditField.Value) && isinteger(app.LayerToEditField.Value)
%                                 app.CurrentSlice = app.VolumeRotated(app.LayerFromEditField.Value:app.LayerToEditField.Value,:,:);
%                             else
%                                 app.LayerFromEditField.Value = floor(app.LayerFromEditField.Value);
%                                 app.LayerToEditField.Value = ceil(app.LayerToEditField.Value);
%                                 app.CurrentSlice = app.VolumeRotated(app.LayerFromEditField.Value:app.LayerToEditField.Value,:,:);
%                             end
%                         % Any other invalid input.
%                         elseif app.LayerFromEditField.Value > app.LayerToEditField.Value
%                             if app.LayerFromEditField.Value >= 1 && app.LayerFromEditField.Value <= app.Volsize(1)
%                                 app.LayerToEditField.Value = floor(app.LayerFromEditField.Value);
%                             elseif app.LayerToEditField.Value >= 1 && app.LayerToEditField.Value <= app.Volsize(1)
%                                 app.LayerFromEditField.Value = ceil(app.LayerToEditField.Value);
%                             end
%                             app.CurrentSlice = app.VolumeRotated(app.LayerFromEditField.Value:app.LayerToEditField.Value,:,:);
%                         elseif app.LayerFromEditField.Value < 1 || app.LayerToEditField.Value > app.Volsize(1)
%                             if app.LayerFromEditField.Value < 1
%                                 app.LayerFromEditField.Value = 1;
%                             end
%                             if app.LayerToEditField.Value > app.Volsize(1)
%                                 app.LayerToEditField.Value = app.Volsize(1);
%                             end
%                             app.CurrentSlice = app.VolumeRotated(app.LayerFromEditField.Value:app.LayerToEditField.Value,:,:);
%                         end
%                         titleStr = strcat("Figure 1. Projection Slice: ",num2str(app.LayerFromEditField.Value),...
%                             " - ", num2str(app.LayerToEditField.Value)," View direction:", app.ViewdirectionDropDown.Value,...
%                             " Euler: ", num2str(app.Spinner_Phi.Value), "\_", num2str(app.Spinner_Theta.Value), "\_", num2str(app.Spinner_Psi.Value));
%                         app.MainImageAxes.Title.String = titleStr;
%                 end
            end
            
            if ~isempty(app.Model)
                SliceModel_aftersliceVolume(app);
            end
        end
        
        function SliceModel_aftersliceVolume(app)
            switch app.DisplayMode
                case 1
                    app.CurrentAtomMask = app.Model.AtomPositions(3,:) >= app.LayerFromEditField.Value & app.Model.AtomPositions(3,:) <= app.LayerToEditField.Value;
                case 0
                    app.CurrentAtomMask = app.ModelRotated.AtomPositions(3,:) >= app.LayerFromEditField.Value & app.ModelRotated.AtomPositions(3,:) <= app.LayerToEditField.Value;
%                     switch app.ViewdirectionDropDown.Value
%                         % For different slice orientation
%                         case 'Z'
%                             app.CurrentAtomMask = app.ModelRotated.AtomPositions(3,:) >= app.LayerFromEditField.Value & app.ModelRotated.AtomPositions(3,:) <= app.LayerToEditField.Value;
%                         case 'Y'
%                             app.CurrentAtomMask = app.ModelRotated.AtomPositions(2,:) >= app.LayerFromEditField.Value & app.ModelRotated.AtomPositions(2,:) <= app.LayerToEditField.Value;
%                         case 'X'
%                             app.CurrentAtomMask = app.ModelRotated.AtomPositions(1,:) >= app.LayerFromEditField.Value & app.ModelRotated.AtomPositions(1,:) <= app.LayerToEditField.Value;
%                     end
            end
        end
        
        function DrawSideView(app)
            % Draw Side View according to the ROI

            position = round(app.roi_position);
            % [xmin, ymin, width, height]
            ymin = position(1);
            xmin = position(2);
            height = position(3);
            width = position(4);
            
            % fprintf("%f,%f,%f,%f",xmin,ymin,width,height);
            % Select volume and model in roi
            app.ROIVolume = app.VolumeRotated(xmin:(xmin+width-1), ymin:(ymin+height-1), :);
            
            % Select origin positions
            if ~isempty(app.Model)
                switch app.DisplayMode
                    case 1
                        side_mask = app.Model.AtomPositions(1,:) >= xmin & app.Model.AtomPositions(1,:) <= (xmin+width-1) & ...
                            app.Model.AtomPositions(2,:) >= ymin & app.Model.AtomPositions(2,:) <= (ymin+height-1);
                        
                    case 0
                        side_mask = app.ModelRotated.AtomPositions(1,:) >= xmin & app.ModelRotated.AtomPositions(1,:) <= (xmin+width-1) & ...
                            app.ModelRotated.AtomPositions(2,:) >= ymin & app.ModelRotated.AtomPositions(2,:) <= (ymin+height-1);
                end
            end
            
            % Create side projection
            SideProjection1 = squeeze(sum(app.ROIVolume,1));
            SideProjection2 = squeeze(sum(app.ROIVolume,2));
            
            %%%%% Draw side view 1 %%%%%
            % Projection view
            app.side_view_1_im = imagesc(app.SideView1Axes, SideProjection1');
            hold(app.SideView1Axes,"on");
            % Layer limit lines
            plot(app.SideView1Axes,[app.SideView1Axes.XLim(1),app.SideView1Axes.XLim(2)],...
                [app.LayerFromEditField.Value,app.LayerFromEditField.Value],"Color",[1,1,0],"LineWidth",1.5);
            plot(app.SideView1Axes,[app.SideView1Axes.XLim(1),app.SideView1Axes.XLim(2)],...
                [app.LayerToEditField.Value,app.LayerToEditField.Value],"Color",[1,1,0],"LineWidth",1.5);
            
            if ~isempty(app.Model)
            % Scatter
                % Initiate
                for type = app.AtomTypeList
                    if ~isempty(app.sidescatter1_im{type})
                        app.sidescatter1_im{type}.delete();
                        app.sidescatter1_im{type} = [];
                    end
                    if ~isempty(app.sidescatter1_add_im{type})
                        app.sidescatter1_add_im{type}.delete();
                        app.sidescatter1_add_im{type} = [];
                    end
                    if ~isempty(app.sidescatter1_delete_im{type})
                        app.sidescatter1_delete_im{type}.delete();
                        app.sidescatter1_delete_im{type} = [];
                    end
                    if ~isempty(app.sidescatter2_im{type})
                        app.sidescatter2_im{type}.delete();
                        app.sidescatter2_im{type} = [];
                    end
                    if ~isempty(app.sidescatter2_add_im{type})
                        app.sidescatter2_add_im{type}.delete();
                        app.sidescatter2_add_im{type} = [];
                    end
                    if ~isempty(app.sidescatter2_delete_im{type})
                        app.sidescatter2_delete_im{type}.delete();
                        app.sidescatter2_delete_im{type} = [];
                    end
                end
                    
                % Draw
                switch app.DisplayMode
                    case 1
                        for type = app.AtomTypeList
                            % Find the type and draw them individually
                            type_mask = (side_mask & (app.Model.AtomType == type));
                            
                            % Original atoms
                            type_mask_origin = (type_mask & app.Model.AtomTracingAttributes == 1);
                            app.sidescatter1_im{type} = scatter(app.SideView1Axes,app.Model.AtomPositions(2,type_mask_origin)-ymin,app.Model.AtomPositions(3,type_mask_origin),...
                                'filled','MarkerEdgeColor',"none","MarkerFaceColor",app.AtomColorList{type},'SizeData',app.AtomsizeEditField.Value);
                            % Add atoms
                            type_mask_add = (type_mask & app.Model.AtomTracingAttributes == 3);
                            app.sidescatter1_add_im{type} = scatter(app.SideView1Axes,app.Model.AtomPositions(2,type_mask_add)-ymin,app.Model.AtomPositions(3,type_mask_add),...
                                '+','MarkerEdgeColor',[1,0.5,0],"LineWidth",2,'SizeData',app.AtomsizeEditField.Value*2);
                            % Delete atoms
                            type_mask_delete = (type_mask & app.Model.AtomTracingAttributes == 0);
                            app.sidescatter1_delete_im{type} = scatter(app.SideView1Axes,app.Model.AtomPositions(2,type_mask_delete)-ymin,app.Model.AtomPositions(3,type_mask_delete),...
                                'o','MarkerEdgeColor',app.AtomColorList{type},"LineWidth",1,'SizeData',app.AtomsizeEditField.Value);
                        end
                    case 0
                        for type = app.AtomTypeList
                            % Find the type and draw them individually
                            type_mask = (side_mask & (app.ModelRotated.AtomType == type));
                            
                            % Original atoms
                            type_mask_origin = (type_mask & app.ModelRotated.AtomTracingAttributes == 3);
                            app.sidescatter1_im{type} = scatter(app.SideView1Axes,app.ModelRotated.AtomPositions(2,type_mask_origin)-ymin,app.ModelRotated.AtomPositions(3,type_mask_origin),...
                                'filled','MarkerEdgeColor',"none","MarkerFaceColor",app.AtomColorList{type},'SizeData',app.AtomsizeEditField.Value);
                            % Add atoms
                            type_mask_add = (type_mask & app.ModelRotated.AtomTracingAttributes == 1);
                            app.sidescatter1_add_im{type} = scatter(app.SideView1Axes,app.ModelRotated.AtomPositions(2,type_mask_add)-ymin,app.ModelRotated.AtomPositions(3,type_mask_add),...
                                '+','MarkerEdgeColor',[1,0.5,0],"LineWidth",2,'SizeData',app.AtomsizeEditField.Value*2);
                            % Delete atoms
                            type_mask_delete = (type_mask & app.ModelRotated.AtomTracingAttributes == 0);
                            app.sidescatter1_delete_im{type} = scatter(app.SideView1Axes,app.ModelRotated.AtomPositions(2,type_mask_delete)-ymin,app.ModelRotated.AtomPositions(3,type_mask_delete),...
                                'o','MarkerEdgeColor',app.AtomColorList{type},"LineWidth",1,'SizeData',app.AtomsizeEditField.Value);
                        end
                end
            end
            
            % Other treatment
            hold(app.SideView1Axes,"off");
            axis(app.SideView1Axes, "image");
            colormap(app.SideView1Axes, app.ColormapDropDown.Value);
            hold(app.SideView1Axes, "on");
            
            %%%%% Draw side view 2 %%%%%
            % Projection view
            app.side_view_2_im = imagesc(app.SideView2Axes, SideProjection2');
            hold(app.SideView2Axes,"on");
            % Layer limit lines
            plot(app.SideView2Axes,[app.SideView2Axes.XLim(1),app.SideView2Axes.XLim(2)],...
                [app.LayerFromEditField.Value,app.LayerFromEditField.Value],"Color",[1,1,0],"LineWidth",1.5);
            plot(app.SideView2Axes,[app.SideView2Axes.XLim(1),app.SideView2Axes.XLim(2)],...
                [app.LayerToEditField.Value,app.LayerToEditField.Value],"Color",[1,1,0],"LineWidth",1.5);
            
            % Draw
            if ~isempty(app.Model)
                switch app.DisplayMode
                    case 1
                        for type = app.AtomTypeList
                            % Find the type and draw them individually
                            type_mask = (side_mask & (app.Model.AtomType == type));
                            
                            % Original atoms
                            type_mask_origin = (type_mask & app.Model.AtomTracingAttributes == 3);
                            app.sidescatter2_im{type} = scatter(app.SideView2Axes,app.Model.AtomPositions(1,type_mask_origin)-xmin,app.Model.AtomPositions(3,type_mask_origin),...
                                'filled','MarkerEdgeColor',"none","MarkerFaceColor",app.AtomColorList{type},'SizeData',app.AtomsizeEditField.Value);
                            % Add atoms
                            type_mask_add = (type_mask & app.Model.AtomTracingAttributes == 1);
                            app.sidescatter2_add_im{type} = scatter(app.SideView2Axes,app.Model.AtomPositions(1,type_mask_add)-xmin,app.Model.AtomPositions(3,type_mask_add),...
                                '+','MarkerEdgeColor',[1,0.5,0],"LineWidth",2,'SizeData',app.AtomsizeEditField.Value*2);
                            % Delete atoms
                            type_mask_delete = (type_mask & app.Model.AtomTracingAttributes == 0);
                            app.sidescatter2_delete_im{type} = scatter(app.SideView2Axes,app.Model.AtomPositions(1,type_mask_delete)-xmin,app.Model.AtomPositions(3,type_mask_delete),...
                                'o','MarkerEdgeColor',app.AtomColorList{type},"LineWidth",1,'SizeData',app.AtomsizeEditField.Value);
                        end
                    case 0
                        for type = app.AtomTypeList
                            % Find the type and draw them individually
                            type_mask = (side_mask & (app.ModelRotated.AtomType == type));
                            
                            % Original atoms
                            type_mask_origin = (type_mask & app.ModelRotated.AtomTracingAttributes == 3);
                            app.sidescatter2_im{type} = scatter(app.SideView2Axes,app.ModelRotated.AtomPositions(1,type_mask_origin)-xmin,app.ModelRotated.AtomPositions(3,type_mask_origin),...
                                'filled','MarkerEdgeColor',"none","MarkerFaceColor",app.AtomColorList{type},'SizeData',app.AtomsizeEditField.Value);
                            % Add atoms
                            type_mask_add = (type_mask & app.ModelRotated.AtomTracingAttributes == 1);
                            app.sidescatter2_add_im{type} = scatter(app.SideView2Axes,app.ModelRotated.AtomPositions(1,type_mask_add)-xmin,app.ModelRotated.AtomPositions(3,type_mask_add),...
                                '+','MarkerEdgeColor',[1,0.5,0],"LineWidth",2,'SizeData',app.AtomsizeEditField.Value*2);
                            % Delete atoms
                            type_mask_delete = (type_mask & app.ModelRotated.AtomTracingAttributes == 0);
                            app.sidescatter2_delete_im{type} = scatter(app.SideView2Axes,app.ModelRotated.AtomPositions(1,type_mask_delete)-xmin,app.ModelRotated.AtomPositions(3,type_mask_delete),...
                                'o','MarkerEdgeColor',app.AtomColorList{type},"LineWidth",1,'SizeData',app.AtomsizeEditField.Value);
                        end
                end
            end
            
            hold(app.SideView2Axes,"off");
            axis(app.SideView2Axes, "image");
            colormap(app.SideView2Axes, app.ColormapDropDown.Value);
            hold(app.SideView2Axes, "on");
        end
        
        function DrawRoiFrame(app)
            % Position information.
            xm = app.roi_position(1);
            ym = app.roi_position(2);
            wd = app.roi_position(3);
            ht = app.roi_position(4);
            % Start to plot roi
            hold(app.MainImageAxes,"on");
            if ~isempty(app.roi_frame_plot)
                app.roi_frame_plot.delete();
                app.roi_frame_plot = [];
            end
            app.roi_frame_plot = plot(app.MainImageAxes,[xm,xm+wd,xm+wd,xm,xm],...
                [ym,ym,ym+ht,ym+ht,ym],'LineWidth',2);
            app.roi_frame_plot.Color = [0.3, 0.6, 1];
            hold(app.MainImageAxes,"off");
        end
        
        function ResetSideView(app)
            app.SideView1Axes.reset();
            app.SideView2Axes.reset();
        end
        
        function ZoomIn(app)
            position = round(app.zoom_position);
            ymin = position(1);
            xmin = position(2);
            height = position(3);
            width = position(4);
            % Select Volume in roi
            app.VolumeRotated = app.OriginalVolume(xmin:(xmin+width), ymin:(ymin+height), :);
            app.CurrentSlice = app.VolumeRotated;
            app.update_mainax();
        end
        
        function coordinates = DistinguishAxes(app)
            % This function aims to distinguish the points you click and
            % return its coordination.
            % If the points belongs to main axes, it will return a positive
            % coordinates;
            % If the points belongs to sideview axes 1, it will return
            % coordinates with the third element -1
            % If the points belongs to sideview axes 2, it will return
            % coordinates with the third element -2
            % If the points belongs to none of these axes, it will return 
            % coordinates [-1, -1, -1]
            
            coordinates = ones(1,3)*-1;
            pt = get(app.MainImageAxes, 'CurrentPoint');
            if pt(1,1) >= app.MainImageAxes.XLim(1) && pt(1,1) <= app.MainImageAxes.XLim(2) && ...
                    pt(1,2) >= app.MainImageAxes.YLim(1) && pt(1,2) <= app.MainImageAxes.YLim(2)
                coordinates = pt(1,1:3);
            else
                pt = get(app.SideView1Axes, 'CurrentPoint');
                if pt(1,1) >= app.SideView1Axes.XLim(1) && pt(1,1) <= app.SideView1Axes.XLim(2) && ...
                    pt(1,2) >= app.SideView1Axes.YLim(1) && pt(1,2) <= app.SideView1Axes.YLim(2)
%                     disp("Points in SideView1Axes")
                    coordinates(1) = pt(1,1) + app.roi_position(1);
                    coordinates(3) = pt(1,2);
                else
                    pt = get(app.SideView2Axes, 'CurrentPoint');
                    if pt(1,1) >= app.SideView2Axes.XLim(1) && pt(1,1) <= app.SideView2Axes.XLim(2) && ...
                        pt(1,2) >= app.SideView2Axes.YLim(1) && pt(1,2) <= app.SideView2Axes.YLim(2)
%                         disp("Points in SideView2Axes")
                        coordinates(2) = pt(1,1) + app.roi_position(2);
                        coordinates(3) = pt(1,2);
                    else
                        app.ShowMessage("<Warning> Point out of axes!");
                    end
                end
            end
        end
        
        function Fine_Trace(app)
            % This function aims to adjust app.temp_add_coordinates to a
            % finer position.
            set(app.UIFigure,'pointer','circle');
            app.finish_fine_trace = 0;
            if ~isempty(app.finetrace1_up_im)
                app.finetrace1_up_im.delete();
                app.finetrace1_up_im = [];
                app.finetrace1_middle_im.delete();
                app.finetrace1_middle_im = [];
                app.finetrace1_down_im.delete();
                app.finetrace1_down_im = [];
            end
            if ~isempty(app.finetrace2_up_im)
                app.finetrace2_up_im.delete();
                app.finetrace2_up_im = [];
                app.finetrace2_middle_im.delete();
                app.finetrace2_middle_im = [];
                app.finetrace2_down_im.delete();
                app.finetrace2_down_im = [];
            end
            
            while ~app.finish_fine_trace
                x = app.temp_add_coordinates(2);
                y = app.temp_add_coordinates(1);
                z = app.temp_add_coordinates(3);
                fineTraceVolume = app.OriginalVolume(x-3:x+3,y-3:y+3,z-1:z+1);
                
                % Timely show add atoms
                if app.DisplayMode == 1
                    app.tempAddDisplay(); 
                end
                
                % Up image
                if app.SideView1TabGroup.SelectedTab == app.FinetraceTab_1
                    
                    app.finetrace1_up_im = imagesc(app.FineTrace1_up_Axes, fineTraceVolume(:,:,3));
                    colormap(app.FineTrace1_up_Axes,"default");
                    caxis(app.FineTrace1_up_Axes,[0,app.maxV]);
                    axis(app.FineTrace1_up_Axes,"image");
                    drawnow;
                    
                    % Middle image
                    app.finetrace1_middle_im = imagesc(app.FineTrace1_middle_Axes, fineTraceVolume(:,:,2));
                    colormap(app.FineTrace1_middle_Axes,"default");
                    caxis(app.FineTrace1_middle_Axes,[0,app.maxV]);
                    axis(app.FineTrace1_middle_Axes,"image");
                    hold(app.FineTrace1_middle_Axes,"on");
                    scatter(app.FineTrace1_middle_Axes,4,4,"MarkerFaceColor",[1,0.5,1],"MarkerEdgeColor",[1,1,1],"LineWidth",1.5)
                    hold(app.FineTrace1_middle_Axes,"off");
                    drawnow;
                
                    % Down image
                    app.finetrace1_down_im = imagesc(app.FineTrace1_down_Axes, fineTraceVolume(:,:,1));
                    colormap(app.FineTrace1_down_Axes,"default");
                    caxis(app.FineTrace1_down_Axes,[0,app.maxV]);
                    axis(app.FineTrace1_down_Axes,"image");
                    drawnow;
                else
                    app.finetrace2_up_im = imagesc(app.FineTrace2_up_Axes, fineTraceVolume(:,:,3));
                    colormap(app.FineTrace2_up_Axes,"default");
                    caxis(app.FineTrace2_up_Axes,[0,app.maxV]);
                    axis(app.FineTrace2_up_Axes,"image");
                    drawnow;

                    app.finetrace2_middle_im = imagesc(app.FineTrace2_middle_Axes, fineTraceVolume(:,:,2));
                    colormap(app.FineTrace2_middle_Axes,"default"); 
                    caxis(app.FineTrace2_middle_Axes,[0,app.maxV]);
                    axis(app.FineTrace2_middle_Axes,"image");
                    hold(app.FineTrace2_middle_Axes,"on");
                    scatter(app.FineTrace2_middle_Axes,4,4,"MarkerFaceColor",[1,0.5,1],"MarkerEdgeColor",[1,1,1],"LineWidth",1.5)
                    hold(app.FineTrace2_middle_Axes,"off");
                    drawnow;

                    app.finetrace2_down_im = imagesc(app.FineTrace2_down_Axes, fineTraceVolume(:,:,1));
                    colormap(app.FineTrace2_down_Axes,"default"); 
                    caxis(app.FineTrace2_down_Axes,[0,app.maxV]);
                    axis(app.FineTrace2_down_Axes,"image");
                    drawnow;
                end
                
                uiwait(app.UIFigure);
                
                if app.finish_fine_trace == -1
                    app.temp_add_coordinates = [];
                end
            end
            
            if app.finish_fine_trace == 1
                type_num = length(app.AtomListBox.Items);
                temp_AtomTypeList = cell(1,type_num + 1);
                for i = 1:type_num
                    temp_AtomTypeList{i} = num2str(i);
                end
                temp_AtomTypeList{type_num + 1} = 'Cancel';
                
                % Selete an atom type
                selection = uiconfirm(app.UIFigure,'Choose an atom type.','Atom type','Options',temp_AtomTypeList);
                
                if strcmp(selection,"Cancel")
                    app.temp_add_coordinates = [];
                else
                    app.Model.AtomType = [app.Model.AtomType, str2double(selection)];
                    app.ModelRotated.AtomType = [app.ModelRotated.AtomType, str2double(selection)];
                end
            end
            
            % Delete fine trace images
            if ~isempty(app.finetrace1_up_im)
                app.finetrace1_up_im.delete();
                app.finetrace1_up_im =[];
                app.finetrace1_middle_im.delete();
                app.finetrace1_middle_im = [];
                app.finetrace1_down_im.delete();
                app.finetrace1_down_im =[];
            end
            if ~isempty(app.finetrace2_up_im)
                app.finetrace2_up_im.delete();
                app.finetrace2_up_im =[];
                app.finetrace2_middle_im.delete();
                app.finetrace2_middle_im =[];
                app.finetrace2_down_im.delete();
                app.finetrace2_down_im = [];
            end
        end
        
        function saveMat(app, fname)
            if ~isempty(app.VolumeRotated)
                switch app.DisplayMode
                    case 1
                        TracerModel.Volume = app.OriginalVolume;
                    case 0
                        TracerModel.Volume = app.VolumeRotated;
                end
                
            end
            if ~isempty(app.Model)
                TracerModel.AtomPositions = app.Model.AtomPositions-(app.Volsize'+1)/2;
                TracerModel.AtomType = app.Model.AtomType;
                TracerModel.AtomTracingAttributes = app.Model.AtomTracingAttributes;
            end
            save(fname,"TracerModel");
            app.save_changed = 1;
        end
        
        function drawScatter(app,ax,data,scatterName)
            for type = app.AtomTypeList
                temp_mask = find((app.typemask{type} & app.CurrentAtomMask)==1);
                temp_s = scatter(ax,data(1,temp_mask),data(2,temp_mask),...
                    'filled','MarkerEdgeColor',"none","MarkerFaceColor",app.AtomColorList{type}); 
                temp_s.SizeData = app.AtomsizeEditField.Value;
                scatterName{type} = temp_s;
            end
        end
        
        function tempAddDisplay(app)
            % This function can timely show the atom you are adding.
            % Only available when app.DisplayMode == 1 (isosurface mode)
            if app.temp_add_coordinates(1) >= app.MainImageAxes.XLim(2) || app.temp_add_coordinates(1) <= app.MainImageAxes.XLim(1)
                disp("Out of range")
            end
            switch app.DisplayMode
                case 1
                    if ~isempty(app.temp_add_coordinates)
                        if ~isempty(app.temp_add_coordinates_sc)
                            app.temp_add_coordinates_sc.delete();
                            app.temp_add_coordinates_sc = [];
                        end
                        hold(app.MainImageAxes,'on');
                        app.temp_add_coordinates_sc = scatter3(app.MainImageAxes, app.temp_add_coordinates(1), app.temp_add_coordinates(2),...
                           app.temp_add_coordinates(3),'magenta',"hexagram","SizeData",app.AtomsizeEditField.Value,'LineWidth',1.5);
                        hold(app.MainImageAxes,'off');
                    else
                        app.ShowMessage("<Warning> app.temp_add_coordinates is empty, unable to show atom(s)!");
                    end
                case 0
                    app.ShowMessage("<Warning> app.tempAddDisplay() is currently only available when app.DisplayMode == 1 (isosurface mode), unable to show atom(s)!");
                    return
            end
            
        end
        
        function ShowMessage(app,msg)
            t = datestr(datetime,13);
            app.MessageBoxText = [strcat("[",t,"]", msg), app.MessageBoxText];
            if length(app.MessageBoxText) > 100
                app.MessageBoxText = app.MessageBoxText(1:100);
            end
            app.MessageBoxTextArea.Value = app.MessageBoxText;
        end
        
        function coordinate = AssignRightCoordinate(app,coordinate)
            switch app.DisplayMode
                case 0
                    if coordinate(2) == -1 % Side axes 1
                        coordinate(2) = app.roi_position(2) + app.roi_position(4) / 2;
%                         switch app.ViewdirectionDropDown.Value
%                             case 'Z'
%                                 coordinate(2) = app.roi_position(2) + app.roi_position(4) / 2;
%                             case 'Y'
%                                 coordinate(2) = coordinate(1);
%                                 coordinate(1) = coordinate(3);
%                                 coordinate(3) = app.roi_position(2) + app.roi_position(4) / 2;
%                             case 'X'
%                                 coordinate(2) = (app.LayerFromEditField.Value + app.LayerToEditField.Value) / 2;
%                         end
                    elseif coordinate(1) == -1 % Side axes 2
                        coordinate(1) = app.roi_position(1) + app.roi_position(3) / 2;
%                         switch app.ViewdirectionDropDown.Value
%                             case 'Z'
%                                 coordinate(3) = (app.LayerFromEditField.Value + app.LayerToEditField.Value) / 2;
%                             case 'Y'
%                                 coordinate(1) = (app.LayerFromEditField.Value + app.LayerToEditField.Value) / 2;
%                             case 'X'
%                                 coordinate(2) = (app.LayerFromEditField.Value + app.LayerToEditField.Value) / 2;
%                         end
                    else
                        coordinate(3) = (app.LayerFromEditField.Value + app.LayerToEditField.Value) / 2;
%                         switch app.ViewdirectionDropDown.Value
%                             case 'Z'
%                                 coordinate(3) = (app.LayerFromEditField.Value + app.LayerToEditField.Value) / 2;
%                             case 'Y'
%                                 coordinate(1) = (app.LayerFromEditField.Value + app.LayerToEditField.Value) / 2;
%                             case 'X'
%                                 coordinate(2) = (app.LayerFromEditField.Value + app.LayerToEditField.Value) / 2;
%                         end
                    end
                case 1
                    if app.MainImageAxes.View(2) == 90  % View from Z
                        coordinate(3) = (app.MainImageAxes.ZLim(1) + app.MainImageAxes.ZLim(2)) / 2;
                    elseif app.MainImageAxes.View(1) == 90  % View from X
                        coordinate(1) = (app.MainImageAxes.XLim(1) + app.MainImageAxes.XLim(2)) / 2;
                    else % View from Y
                        coordinate(2) = (app.MainImageAxes.YLim(1) + app.MainImageAxes.YLim(2)) / 2;
                    end
            end
            coordinate = round(coordinate);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function tracerStartupFcn(app)
            app.init();
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            if app.save_changed == 1
                choice = uiconfirm(app.UIFigure,"Close Tracer?","Confirm exit",'Options',{'OK','Cancel'},'CancelOption',2);
                switch choice
                    case "OK"
                        delete(app.UIFigure);
                        return;
                    case "Cancel"
                        return;
                end
            elseif app.save_changed == 0
                choice = uiconfirm(app.UIFigure,"You have unsaved changes! Do you want to save?","Confirm exit",'Options',{'Save','Not save','Cancel'},'CancelOption',3);
                switch choice
                    case "Save"
                        app.MATfilematMenuSelected();
                        if app.save_changed == 1
                            delete(app.UIFigure);
                        end
                    case "Not save"
                        delete(app.UIFigure);
                        return;
                    case "Cancel"
                        return;
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.save_changed == 1
                choice = uiconfirm(app.UIFigure,"Close Tracer?","Confirm exit",'Options',{'OK','Cancel'},'CancelOption',2);
                switch choice
                    case "OK"
                        delete(app.UIFigure);
                        return;
                    case "Cancel"
                        return;
                end
            elseif app.save_changed == 0
                choice = uiconfirm(app.UIFigure,"You have unsaved changes! Do you want to save?","Confirm exit",'Options',{'Save','Not save','Cancel'},'CancelOption',3);
                switch choice
                    case "Save"
                        app.MATfilematMenuSelected();
                    case "Not save"
                        delete(app.UIFigure);
                        return;
                    case "Cancel"
                        return;
                end
            end
        end

        % Value changed function: Slider_Phi
        function Slider_PhiValueChanged(app, event)
            app.Phi = app.Slider_Phi.Value;
            app.Spinner_Phi.Value = app.Slider_Phi.Value;
            % Rotate volume
            app.ViewdirectionDropDownValueChanged();

%             app.RotateVolume();
%             update_mainax(app);
%             ResetSideView(app);
%             if ~isempty(app.roi_position)
%                 DrawSideView(app);
%             end
            
            app.ShowMessage(strcat("Phi angle changed to ", num2str(app.Phi)));
        end

        % Value changed function: Spinner_Phi
        function Spinner_PhiValueChanged(app, event)
            app.Phi = app.Spinner_Phi.Value;
            app.Slider_Phi.Value = app.Spinner_Phi.Value;
            app.ViewdirectionDropDownValueChanged();

%             app.RotateVolume();
%             update_mainax(app);
%             ResetSideView(app);
%             if ~isempty(app.roi_position)
%                 DrawSideView(app);
%             end
            
            app.ShowMessage(strcat("Phi angle changed to ", num2str(app.Phi)));
        end

        % Value changing function: Slider_Phi
        function Slider_PhiValueChanging(app, event)
            changingValue = event.Value;
            app.Spinner_Phi.Value = changingValue;
        end

        % Value changing function: Spinner_Phi
        function Spinner_PhiValueChanging(app, event)
            changingValue = event.Value;
            app.Slider_Phi.Value = changingValue;
        end

        % Value changed function: Slider_Theta
        function Slider_ThetaValueChanged(app, event)
            app.Theta = app.Slider_Theta.Value;
            app.Spinner_Theta.Value = app.Slider_Theta.Value;
            app.ViewdirectionDropDownValueChanged();

%             app.RotateVolume();
%             update_mainax(app);
%             ResetSideView(app);
%             if ~isempty(app.roi_position)
%                 DrawSideView(app);
%             end
            
            app.ShowMessage(strcat("Theta angle changed to ", num2str(app.Theta)));
        end

        % Value changed function: Spinner_Theta
        function Spinner_ThetaValueChanged(app, event)
            app.Theta = app.Spinner_Theta.Value;
            app.Slider_Theta.Value = app.Spinner_Theta.Value;
            app.ViewdirectionDropDownValueChanged();

%             app.RotateVolume();
%             update_mainax(app);
%             ResetSideView(app);
%             if ~isempty(app.roi_position)
%                 DrawSideView(app);
%             end
            
            app.ShowMessage(strcat("Theta angle changed to ", num2str(app.Theta)));
        end

        % Value changing function: Slider_Theta
        function Slider_ThetaValueChanging(app, event)
            changingValue = event.Value;
            app.Spinner_Theta.Value = changingValue;
        end

        % Value changing function: Spinner_Theta
        function Spinner_ThetaValueChanging(app, event)
            changingValue = event.Value;
            app.Slider_Theta.Value = changingValue;
        end

        % Value changed function: Slider_Psi
        function Slider_PsiValueChanged(app, event)
            app.Psi = app.Slider_Psi.Value;
            app.Spinner_Psi.Value = app.Slider_Psi.Value;
            app.ViewdirectionDropDownValueChanged();

%             app.RotateVolume();
%             update_mainax(app);
%             ResetSideView(app);
%             if ~isempty(app.roi_position)
%                 DrawSideView(app);
%             end
            
            app.ShowMessage(strcat("Psi angle changed to ", num2str(app.Psi)));
        end

        % Value changed function: Spinner_Psi
        function Spinner_PsiValueChanged(app, event)
            app.Psi = app.Spinner_Psi.Value;
            app.Slider_Psi.Value = app.Spinner_Psi.Value;
            app.ViewdirectionDropDownValueChanged();

%             app.RotateVolume();
%             update_mainax(app);
%             ResetSideView(app);
%             if ~isempty(app.roi_position)
%                 DrawSideView(app);
%             end
            
            app.ShowMessage(strcat("Psi angle changed to ", num2str(app.Psi)));
        end

        % Value changing function: Slider_Psi
        function Slider_PsiValueChanging(app, event)
            changingValue = event.Value;
            app.Spinner_Psi.Value = changingValue;
        end

        % Value changing function: Spinner_Psi
        function Spinner_PsiValueChanging(app, event)
            changingValue = event.Value;
            app.Slider_Psi.Value = changingValue;
        end

        % Button pushed function: ApplyrotationButton
        function ApplyrotationButtonPushed(app, event)
            choice = uiconfirm(app.UIFigure,"Apply rotation? This will apply the interpolation","Apply rotation",'Options',{'OK','Cancel'},'CancelOption',2);
            switch choice
                case "OK"
                    app.Model = app.ModelRotated;
                    app.OriginalVolume = app.VolumeRotated;
                    app.Slider_Phi.Value = 0;
                    app.Slider_Psi.Value = 0;
                    app.Slider_Theta.Value = 0;
                    app.Spinner_Phi.Value = 0;
                    app.Spinner_Psi.Value = 0;
                    app.Spinner_Theta.Value = 0;
                    return;
                case "Cancel"
                    return;
            end
        end

        % Menu selected function: VolumeMenu
        function VolumeMenuSelected(app, event)
            
            [fileName, pathName] = uigetfile();
            if ~isequal([fileName,pathName],[0,0])
                filepath = fullfile(pathName, fileName);
                app.loadVolume(filepath);
                app.init();
            end
        end

        % Menu selected function: MATfilematMenu
        function MATfilematMenuSelected(app, event)
            t = num2str(yyyymmdd(datetime));
            savestr = strcat("TracerOutput_",t,".mat");
            [fileName, pathName] = uiputfile(savestr);
            if ~isequal([fileName,pathName],[0,0])
                filepath = fullfile(pathName, fileName);
                app.saveMat(filepath);
            end
        end

        % Value changed function: LayerFromEditField
        function LayerFromEditFieldValueChanged(app, event)
            app.SliceVolume();
            update_mainax(app,1);
        end

        % Value changed function: LayerToEditField
        function LayerToEditFieldValueChanged(app, event)
            app.SliceVolume();
            update_mainax(app,1);
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            update_mainax(app,1);
        end

        % Button pushed function: LayerupButton
        function LayerupButtonPushed(app, event)
            app.LayerFromEditField.Value = app.LayerFromEditField.Value + app.ChangestepSpinner.Value;
            app.LayerToEditField.Value = app.LayerToEditField.Value + app.ChangestepSpinner.Value;
            app.SliceVolume();
            update_mainax(app);
        end

        % Button pushed function: LayerdownButton
        function LayerdownButtonPushed(app, event)
            app.LayerFromEditField.Value = app.LayerFromEditField.Value - app.ChangestepSpinner.Value;
            app.LayerToEditField.Value = app.LayerToEditField.Value - app.ChangestepSpinner.Value;
            app.SliceVolume();
            update_mainax(app);
        end

        % Value changed function: ChangestepSpinner_2
        function ChangestepSpinner_2ValueChanged(app, event)
            step = app.ChangestepSpinner_2.Value;
            app.Spinner_Phi.Step = step;
            app.Spinner_Theta.Step = step;
            app.Spinner_Psi.Step = step;
        end

        % Value changed function: ViewdirectionDropDown
        function ViewdirectionDropDownValueChanged(app, event)
            switch app.DisplayMode
                case 1
                    if strcmp(app.ViewdirectionDropDown.Value, "Z")
                        app.CameraView = [0,90];
                        app.MainImageAxes.View = app.CameraView;
                    elseif strcmp(app.ViewdirectionDropDown.Value, "Y")
                        app.CameraView = [0,0];
                        app.MainImageAxes.View = app.CameraView;
                    elseif strcmp(app.ViewdirectionDropDown.Value, "X")
                        app.CameraView = [90,0];
                        app.MainImageAxes.View = app.CameraView;
                    end
%                     app.SliceVolume();
%                     app.update_mainax(3);
                    
                    if ~isempty(app.roi_position)
                        ResetSideView(app);
                        app.DrawSideView();
                    end
                case 0
                    switch app.ViewdirectionDropDown.Value
                        case 'Z'
                            app.Phi = app.Spinner_Phi.Value;
                            app.Theta = app.Spinner_Theta.Value;
                            app.Psi = app.Spinner_Psi.Value;
                        case 'Y'
                            app.Phi = app.Spinner_Phi.Value;
                            app.Theta = app.Spinner_Theta.Value - 90;
                            app.Psi = app.Spinner_Psi.Value;
                        case 'X'
                            app.Phi = app.Spinner_Phi.Value - 90;
                            app.Theta = app.Spinner_Theta.Value;
                            app.Psi = app.Spinner_Psi.Value - 90;
                    end
                    if ~isempty(app.Model) && app.ShowatomButton.Value
                        app.RotateModel();
                    end 
                    app.RotateVolume();
                    app.SliceVolume();
                    app.update_mainax(1);
                    if ~isempty(app.roi_position)
                        ResetSideView(app);
                        app.DrawSideView();
                    end
            end
            
        end

        % Button pushed function: DrawmaskButton
        function DrawmaskButtonPushed(app, event)
            if strcmp(app.MasktypeDropDown.Value, "Rectangle")
                roi_temp = drawrectangle(app.MainImageAxes);
            end
            app.roi_position = roi_temp.Position;
            app.RectanglerangeYEditField.Value = round(app.roi_position(1));
            app.RectanglerangeXEditField.Value = round(app.roi_position(2));
            app.RectanglerangeYEditField_2.Value = round(app.roi_position(1)+app.roi_position(3));
            app.RectanglerangeXEditField_2.Value = round(app.roi_position(2)+app.roi_position(4));
            
            roi_temp.delete();
            app.DrawRoiFrame();
            app.ResetSideView();
            app.DrawSideView();
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            if app.RectanglerangeYEditField_2.Value > app.RectanglerangeYEditField.Value &&...
                    app.RectanglerangeXEditField_2.Value > app.RectanglerangeXEditField.Value
                app.roi_position = [app.RectanglerangeYEditField.Value, app.RectanglerangeXEditField.Value, ...
                app.RectanglerangeYEditField_2.Value-app.RectanglerangeYEditField.Value, app.RectanglerangeXEditField_2.Value-app.RectanglerangeXEditField.Value];
                app.DrawRoiFrame();
                app.ResetSideView();
                app.DrawSideView();
            else
                app.ShowMessage("<Warning> Incorrect rectangle range input. Failed to draw mask!");
            end
        end

        % Value changed function: DisplaymodeDropDown
        function DisplaymodeDropDownValueChanged(app, event)
            switch app.DisplaymodeDropDown.Value
                case "Isosurface"
                    app.LightOnButton.Enable = 1;
                    app.Slider_Isovalue.Enable = 1;
                    app.IsoAlphaSlider.Enable = 1;
                    app.IsoColorButton.Enable = 1;
                    app.DisplayMode = 1;
                    d = uiprogressdlg(app.UIFigure,'Title','Computing isosurface','Indeterminate','on');
                    update_mainax(app);
                    close(d);
                    app.ShowMessage("Successfully draw isosurface!");
                case "Projection"
                    app.ModelRotated = app.Model;
                    app.MainImageAxes.View = [0,90];
                    app.LightOnButton.Enable = 0;
                    app.Slider_Isovalue.Enable = 0;
                    app.IsoAlphaSlider.Enable = 0;
                    app.IsoColorButton.Enable = 0;
                    app.DisplayMode = 0;
                    update_mainax(app);
                    app.ShowMessage("Successfully draw projection!");
            end
            
        end

        % Value changed function: Slider_Isovalue
        function Slider_IsovalueValueChanged(app, event)
            update_mainax(app);
        end

        % Button pushed function: LightOnButton
        function LightOnButtonPushed(app, event)
            if (app.LightOnButton.Text == "Light On")
                app.LightOnButton.Text = "Light Off";
                if app.DisplayMode == 1
                    lighting(app.MainImageAxes,"none");
                end
            else
                app.LightOnButton.Text = "Light On";
                if app.DisplayMode == 1
                    lighting(app.MainImageAxes,"flat");
                end
            end
        end

        % Button pushed function: IsoColorButton
        function IsoColorButtonPushed(app, event)
            c = uisetcolor(app.main_projection_p.FaceColor);
            app.IsoColorDisplayLabel.BackgroundColor = c;
            app.main_projection_p.FaceColor = c;
        end

        % Value changed function: IsoAlphaSlider
        function IsoAlphaSliderValueChanged(app, event)
            isovalue = app.IsoAlphaSlider.Value;
            app.main_projection_p.FaceAlpha = isovalue;
        end

        % Button pushed function: DrawZoomROIButton
        function DrawZoomROIButtonPushed(app, event)
            roi_temp = drawrectangle(app.MainImageAxes);

            app.zoom_position = roi_temp.Position;
            roi_temp.delete();
            app.ZoomIn();
            app.DisplaymodeDropDown.Value = "Isosurface";
            app.DisplaymodeDropDownValueChanged();
            app.ResetSideView();
            if ~isempty(app.roi_frame_plot)
                app.roi_frame_plot.delete();
                app.roi_frame_plot = [];
                app.side_view_1_im.delete();
                app.side_view_1_im = [];
                app.side_view_2_im.delete();
                app.side_view_2_im = [];
            end
        end

        % Button pushed function: ResetZoomROIButton
        function ResetZoomROIButtonPushed(app, event)
            app.zoom_position = [];
            app.ModelRotated = app.Model;
            app.init();
        end

        % Value changed function: ShowatomButton
        function ShowatomButtonValueChanged(app, event)
            if ~isempty(app.main_projection_atom_scatter)
                for type = app.AtomTypeList
                    app.main_projection_atom_scatter{type}.Visible = app.ShowatomButton.Value;
                end
            end
        end

        % Button pushed function: AtomColorButton
        function AtomColorButtonPushed(app, event)
            type = app.AtomListBox.Value(end);
            type = str2double(type);
            c = uisetcolor(app.main_projection_atom_scatter{type}.MarkerFaceColor);
            app.AtomColorList{type} = c;
            app.main_projection_atom_scatter{type}.MarkerFaceColor = c;
            
        end

        % Value changed function: AtomsizeEditField
        function AtomsizeEditFieldValueChanged(app, event)
            for type = app.AtomTypeList
                
                if ~isempty(app.main_projection_atom_scatter{type})
                    app.main_projection_atom_scatter{type}.SizeData = app.AtomsizeEditField.Value;
                end
                if ~isempty(app.main_projection_add_scatter{type})
                    app.main_projection_add_scatter{type}.SizeData = app.AtomsizeEditField.Value*2;
                end
                if ~isempty(app.main_projection_delete_scatter{type})
                    app.main_projection_delete_scatter{type}.SizeData = app.AtomsizeEditField.Value;
                end
            end
        end

        % Value changed function: StarttracingButton
        function StarttracingButtonValueChanged(app, event)
            app.start_trace = app.StarttracingButton.Value;
            if app.start_trace == 1
                app.AddButton.Enable = 1;
                app.DeleteRestoreButton.Enable = 1;
                app.RedoButton.Enable = 1;
                app.UndoButton.Enable = 1;
            else
                app.AddButton.Enable = 0;
                app.DeleteRestoreButton.Enable = 0;
                app.RedoButton.Enable = 0;
                app.UndoButton.Enable = 0;
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % Set the pointer's shape
            % Pointer: [ crosshair | fullcrosshair | {arrow} | ibeam | watch | topl | topr | botl | botr | left | top | right | bottom | circle | cross | fleur | custom | hand ]
            
            set(app.UIFigure,'pointer','cross');
            
            % Set the current trace state as add (1)
            app.trace_state = 1;
            
            % choice - "OK" or "Cancel", "Quit Add". if "OK", continue; if "Quit Add", break.
            choice = "Cancel";
            while ~strcmp(choice,"OK")
                app.temp_add_coordinates = ones(1,3)*-1; % Ready for input
                uiwait(app.UIFigure);
                
                % Assign right coordinate
                app.temp_add_coordinates = app.AssignRightCoordinate(app.temp_add_coordinates);
                
                % Confirm part
                if app.temp_add_coordinates(3) >= 0
                    confirm_msg = strcat("Confirm add? Coordination: x = ",num2str(app.temp_add_coordinates(1)),...
                        "; y = ",num2str(app.temp_add_coordinates(2)),"; z = ",num2str(app.temp_add_coordinates(3)));
                    choice = uiconfirm(app.UIFigure,confirm_msg,"Confirm exit",'Options',{'OK','Cancel','Quit Add'},'CancelOption',2);
                    % Quit add
                    if strcmp(choice,'Quit Add')
                        set(app.UIFigure,'pointer','arrow')
                        app.trace_state = 0;
                        app.temp_add_coordinates = ones(1,3)*-1;
                        return;
                    end
                end
            end
            set(app.UIFigure,'pointer','arrow')

            % Start fine trace
            if app.SideView1Axes.XLim(2) < app.SideView2Axes.XLim(2)
                app.SideView1TabGroup.SelectedTab = app.FinetraceTab_1;
            else
                app.SideView2TabGroup.SelectedTab = app.FinetraceTab_2;
            end
            % Prepare for fine trace
            app.FineTrace1_down_Axes.Visible = 1;
            app.FineTrace1_up_Axes.Visible = 1;
            app.FineTrace1_middle_Axes.Visible = 1;
            app.FineTrace2_down_Axes.Visible = 1;
            app.FineTrace2_up_Axes.Visible = 1;
            app.FineTrace2_middle_Axes.Visible = 1;
            app.finetrace_state = 1;
            % START FINE TRACE
            app.Fine_Trace();
            % Finish and other treatment
            app.finetrace_state = 0;
            app.FineTrace1_down_Axes.Visible = 0;
            app.FineTrace1_up_Axes.Visible = 0;
            app.FineTrace1_middle_Axes.Visible = 0;
            app.FineTrace2_down_Axes.Visible = 0;
            app.FineTrace2_up_Axes.Visible = 0;
            app.FineTrace2_middle_Axes.Visible = 0;
            
            app.SideView1TabGroup.SelectedTab = app.Sideview1Tab;
            app.SideView2TabGroup.SelectedTab = app.Sideview2Tab;
            set(app.UIFigure,'pointer','arrow')
            
            % If abort fine trace
            if isempty(app.temp_add_coordinates)
                app.trace_state = 0;
                app.temp_add_coordinates = ones(1,3)*-1;
                return;
            end
            
            % Delete timely show atom if exsited
            if ~isempty(app.temp_add_coordinates_sc)
                app.temp_add_coordinates_sc.delete();
                app.temp_add_coordinates_sc = [];
            end
            
            % Update actions, add info
            app.AddAtomNum = app.AddAtomNum + 1;
            app.ActionsNum = app.ActionsNum + 1;
            % app.ActionsList(app.ActionsNum) = [-1;1];
            app.trace_state = 0;
            
            app.CurrentAtomMask = [app.CurrentAtomMask, 1];
            addatomposition = [app.temp_add_coordinates(1,2);app.temp_add_coordinates(1,1);app.temp_add_coordinates(1,3)];
            app.temp_add_coordinates = ones(1,3)*-1;
            switch app.DisplayMode
                case 1
                    app.Model.AtomPositions = [app.Model.AtomPositions, addatomposition];
                    app.Model.AtomTracingAttributes = [app.Model.AtomTracingAttributes, 1];
                    app.ModelRotated = app.Model;
                case 0
                    app.ModelRotated.AtomPositions = [app.ModelRotated.AtomPositions, addatomposition];
                    app.ModelRotated.AtomTracingAttributes = [app.ModelRotated.AtomTracingAttributes, 1];
                    app.Model.AtomTracingAttributes = [app.Model.AtomTracingAttributes, 1];
                    app.temp_add_coordinates = ones(1,3)*-1;
                    % From app.ModelRotated to app.Model
                    radPhi = deg2rad(app.Phi);
                    radTheta = deg2rad(app.Theta);
                    radPsi = deg2rad(app.Psi);
                    rotate_matrix = [
                        cos(radPhi) 0 sin(radPhi);
                        0 1 0;
                        -sin(radPhi) 0 cos(radPhi)];
                    rotate_matrix = [
                        1 0 0;
                        0 cos(radTheta) -sin(radTheta);
                        0 sin(radTheta) cos(radTheta);] * rotate_matrix;
                    rotate_matrix = [
                        cos(radPsi) -sin(radPsi) 0;
                        sin(radPsi) cos(radPsi) 0;
                        0 0 1] * rotate_matrix; 
                    app.Model.AtomPositions = [app.Model.AtomPositions, (rotate_matrix)\(addatomposition-(app.Volsize'+1)/2)+(app.Volsize'+1)/2];
            end
            
            
            % Update images
            for type = app.AtomTypeList
                if ~isempty(app.main_projection_add_scatter{type})
                    app.main_projection_add_scatter{type}.delete();
                    app.temp_add_coordinates_sc = [];
                end
            end
            hold(app.MainImageAxes,"on");
            app.SliceVolume();
            app.update_mainax(3);
            if ~isempty(app.roi_position)
                ResetSideView(app);
                app.DrawSideView();
            end
            app.ShowMessage("Successfully add an atom!");
            app.save_changed = 0;
            hold(app.MainImageAxes,"off");
            
            
        end

        % Button pushed function: DeleteRestoreButton
        function DeleteRestoreButtonPushed(app, event)
            set(app.UIFigure,'pointer','cross')
            
            % Set the current trace state as delete (2)
            app.trace_state = 2;
            
            app.temp_delete_coordinates = ones(1,3)*-1; % Ready for input
            uiwait(app.UIFigure);
            set(app.UIFigure,'pointer','arrow')
            
%             if app.DisplayMode == 0
%                 if strcmp(app.ViewdirectionDropDown.Value,'Z')
%                     app.temp_delete_coordinates(3) = (app.LayerFromEditField.Value + app.LayerToEditField.Value) / 2;
%                 elseif strcmp(app.ViewdirectionDropDown.Value,'Y')
%                     app.temp_delete_coordinates(1) = (app.LayerFromEditField.Value + app.LayerToEditField.Value) / 2;
%                 else
%                     app.temp_delete_coordinates(2) = (app.LayerFromEditField.Value + app.LayerToEditField.Value) / 2;
%                 end
%             elseif app.DisplayMode == 1
%                 if app.MainImageAxes.View(2) == 90  % View from Z
%                     app.temp_delete_coordinates(3) = (app.MainImageAxes.ZLim(1) + app.MainImageAxes.ZLim(2)) / 2;
%                 elseif app.MainImageAxes.View(1) == 90  % View from X
%                     app.temp_delete_coordinates(1) = (app.MainImageAxes.XLim(1) + app.MainImageAxes.XLim(2)) / 2;
%                 else % View from Y
%                     app.temp_delete_coordinates(2) = (app.MainImageAxes.YLim(1) + app.MainImageAxes.YLim(2)) / 2;
%                 end
%             end
            app.temp_delete_coordinates = app.AssignRightCoordinate(app.temp_delete_coordinates);
            
            app.temp_delete_coordinates = [app.temp_delete_coordinates(2), app.temp_delete_coordinates(1), app.temp_delete_coordinates(3)];
            search_range = 10;
            delete_pend_list = [];
            
            switch app.DisplayMode % Projection mode
                case 0
                    % Search for the neareast atoms
                    for i = 2:search_range
                        pend_mask = (app.ModelRotated.AtomPositions(2,:) > app.temp_delete_coordinates(2) - i & app.ModelRotated.AtomPositions(2,:) < app.temp_delete_coordinates(2) + i) &...
                            (app.ModelRotated.AtomPositions(1,:) > app.temp_delete_coordinates(1) - i & app.ModelRotated.AtomPositions(1,:) < app.temp_delete_coordinates(1) + i) &...
                            (app.ModelRotated.AtomPositions(3,:) > app.temp_delete_coordinates(3) - i & app.ModelRotated.AtomPositions(3,:) < app.temp_delete_coordinates(3) + i) &...
                            logical(app.CurrentAtomMask);
                        if sum(pend_mask)
                            delete_pend_list = app.ModelRotated.AtomPositions(:,pend_mask);
                            delete_pend_ind_list = find(pend_mask);
                            break;
                        else
                            if i ~= search_range
                                continue;
                            else
                                confirm_msg = "No atom(s) detected around your selection!";
                                uiconfirm(app.UIFigure,confirm_msg,"Confirm exit",'Options',{'Cancel'},'CancelOption',1);
                                app.trace_state = 0;
                                return;
                            end
                        end
                    end
                case 1
                    for i = 2:search_range
                        % Search for the neareast atoms
                        pend_mask = (app.Model.AtomPositions(2,:) > app.temp_delete_coordinates(2) - i & app.Model.AtomPositions(2,:) < app.temp_delete_coordinates(2) + i) &...
                            (app.Model.AtomPositions(1,:) > app.temp_delete_coordinates(1) - i & app.Model.AtomPositions(1,:) < app.temp_delete_coordinates(1) + i) &...
                            (app.Model.AtomPositions(3,:) > app.temp_delete_coordinates(3) - i & app.Model.AtomPositions(3,:) < app.temp_delete_coordinates(3) + i) &...
                            logical(app.CurrentAtomMask);
                        if sum(pend_mask)
                            delete_pend_list = app.Model.AtomPositions(:,pend_mask);
                            delete_pend_ind_list = find(pend_mask);
                            break;
                        else
                            if i ~= search_range
                                continue;
                            else
                                confirm_msg = "No atom(s) detected around your selection!";
                                uiconfirm(app.UIFigure,confirm_msg,"Confirm exit",'Options',{'Cancel'},'CancelOption',1);
                                app.trace_state = 0;
                                return;
                            end
                        end
                    end
            end
            
            
            distance_list = zeros(1,size(delete_pend_list,2));
            for i = 1:size(delete_pend_list,2)
                distance_list(i) = norm(delete_pend_list(:,i)-app.temp_delete_coordinates);
            end
            [~,ind] = min(distance_list); % Find the nearest atom.
            delete_coordinate_ind_final = delete_pend_ind_list(ind); % The index of the nearest atom.
            % Modify the delete/restore information.
            if (app.Model.AtomTracingAttributes(delete_coordinate_ind_final) == 1) || (app.Model.AtomTracingAttributes(delete_coordinate_ind_final) == 3)
                app.DeleteAtomNum = app.DeleteAtomNum + 1;
%                     app.delete_ind_list(app.DeleteAtomNum) = find((app.Model.AtomPositions(1,:) == delete_coordinate_final(1))&...
%                         (app.Model.AtomPositions(2,:) == delete_coordinate_final(2))&...
%                         (app.Model.AtomPositions(3,:) == delete_coordinate_final(3))); % For undo and redo actions  
                app.delete_ind_list(app.DeleteAtomNum) = delete_coordinate_ind_final;
                app.ActionsNum = app.ActionsNum + 1;
                app.Model.AtomTracingAttributes(delete_coordinate_ind_final) = 0;
                app.ModelRotated.AtomTracingAttributes(delete_coordinate_ind_final) = 0;
                app.save_changed = 0;
                app.ShowMessage("Successfully delete an atom!");
            elseif app.Model.AtomTracingAttributes(delete_coordinate_ind_final) == 0
                app.DeleteAtomNum = app.DeleteAtomNum - 1;
                app.delete_ind_list(app.delete_ind_list == delete_coordinate_ind_final) = [];
                if delete_coordinate_ind_final > app.originAtomNum
                    app.Model.AtomTracingAttributes(delete_coordinate_ind_final) = 1;
                    app.ModelRotated.AtomTracingAttributes(delete_coordinate_ind_final) = 1;
                else
                    app.Model.AtomTracingAttributes(delete_coordinate_ind_final) = app.OriginalAtomTracingAttributes(delete_coordinate_ind_final);
                    app.ModelRotated.AtomTracingAttributes(delete_coordinate_ind_final) = app.OriginalAtomTracingAttributes(delete_coordinate_ind_final);
                end
                app.save_changed = 0;
                app.ShowMessage("Successfully restore an atom!");
            end
            
            app.SliceVolume();
            app.update_mainax(3);
            if ~isempty(app.roi_position)
                ResetSideView(app);
                app.DrawSideView();
            end
            app.trace_state = 0;
            
            
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)
            app.ShowMessage("<Error> Redo not available now!");
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            app.ShowMessage("<Error> Undo not available now!");
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if app.StarttracingButton.Value
                if app.trace_state == 1 && app.finetrace_state ~= 1
                    app.temp_add_coordinates = app.DistinguishAxes();
                    if app.DisplayMode == 1
                        if ~isempty(app.temp_add_coordinates_sc)
                            app.temp_add_coordinates_sc.delete();
                            app.temp_add_coordinates_sc = [];
                        end
                        hold(app.MainImageAxes,'on');
                    end
                    
                    uiresume(app.UIFigure);
                elseif app.trace_state == 2
                    app.temp_delete_coordinates = app.DistinguishAxes();
                    uiresume(app.UIFigure);
                elseif app.trace_state == 3
                    
                end
            end
            
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            if app.finetrace_state == 1
                switch key
                    case 'rightarrow'
                        app.temp_add_coordinates(1) = app.temp_add_coordinates(1)+1; uiresume(app.UIFigure);
                    case 'd'
                        app.temp_add_coordinates(1) = app.temp_add_coordinates(1)+1; uiresume(app.UIFigure);
                    case 'leftarrow'
                        app.temp_add_coordinates(1) = app.temp_add_coordinates(1)-1; uiresume(app.UIFigure);
                    case 'a'
                        app.temp_add_coordinates(1) = app.temp_add_coordinates(1)-1; uiresume(app.UIFigure);
                    case 'uparrow'
                        app.temp_add_coordinates(2) = app.temp_add_coordinates(2)-1; uiresume(app.UIFigure);
                    case 'w'
                        app.temp_add_coordinates(2) = app.temp_add_coordinates(2)-1; uiresume(app.UIFigure);
                    case 'downarrow'
                        app.temp_add_coordinates(2) = app.temp_add_coordinates(2)+1; uiresume(app.UIFigure);
                    case 's'
                        app.temp_add_coordinates(2) = app.temp_add_coordinates(2)+1; uiresume(app.UIFigure);
                    case 'space'
                        app.temp_add_coordinates(3) = app.temp_add_coordinates(3)+1; uiresume(app.UIFigure);
                    case 'add'
                        app.temp_add_coordinates(3) = app.temp_add_coordinates(3)+1; uiresume(app.UIFigure);
                    case 'shift'
                        app.temp_add_coordinates(3) = app.temp_add_coordinates(3)-1; uiresume(app.UIFigure);
                    case 'subtract'
                        app.temp_add_coordinates(3) = app.temp_add_coordinates(3)-1; uiresume(app.UIFigure);
                    case 'q'
                        app.finish_fine_trace = -1; app.temp_add_coordinates_sc.delete(); app.temp_add_coordinates_sc = []; uiresume(app.UIFigure);
                    case 'f'
                        app.finish_fine_trace = 1; uiresume(app.UIFigure);
                    case 'r'
                        h = rotate3d(app.UIFigure);
                        h.Enable = 'on';
                end
            elseif app.finetrace_state == 2
                
            else
                switch key
                    case 'a'
                        app.AddButtonPushed();
                    case 'd'
                        app.DeleteRestoreButtonPushed();
                    case 'q'
                        app.ExitMenu();
                    case 'r'
                        h = rotate3d(app.UIFigure);
                        h.Enable = 'on';
                    case 'rightarrow'
                        app.LayerupButtonPushed();
                    case 'leftarrow'
                        app.LayerdownButtonPushed();
                    case 'y'
                        app.RedoButtonPushed();
                    case 'z'
                        app.UndoButtonPushed();
                    case 'v'
                        app.DrawButtonPushed();
                end
            end 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Tooltip = {'File'};
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.Text = 'Import';
            app.ObjectMenu = uimenu(app.ImportMenu);
            app.ObjectMenu.Text = 'Object';
            app.VolumeMenu = uimenu(app.ImportMenu);
            app.VolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @VolumeMenuSelected, true);
            app.VolumeMenu.Text = 'Volume';
            app.AtommodelMenu = uimenu(app.ImportMenu);
            app.AtommodelMenu.Text = 'Atom model';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.XYZfilexyzMenu = uimenu(app.SaveMenu);
            app.XYZfilexyzMenu.Text = 'XYZ file (.xyz)';
            app.MATfilematMenu = uimenu(app.SaveMenu);
            app.MATfilematMenu.MenuSelectedFcn = createCallbackFcn(app, @MATfilematMenuSelected, true);
            app.MATfilematMenu.Text = 'MAT file (.mat)';
            app.RAWfilerawMenu = uimenu(app.SaveMenu);
            app.RAWfilerawMenu.Text = 'RAW file (.raw)';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Tooltip = {'Edit'};
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Tooltip = {'View'};
            app.ViewMenu.Text = 'View';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Tooltip = {'Export'};
            app.ExportMenu.Text = 'Export';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MainImageAxes = uiaxes(app.GridLayout);
            title(app.MainImageAxes, 'Figure 1. Projection View')
            xlabel(app.MainImageAxes, 'X')
            ylabel(app.MainImageAxes, 'Y')
            zlabel(app.MainImageAxes, 'Z')
            app.MessageBoxPanel = uipanel(app.GridLayout);
            app.MessageBoxPanel.Title = 'Message Box';
            app.MessageBoxTextArea = uitextarea(app.MessageBoxPanel);
            app.MessageBoxTextArea.Editable = 'off';
            app.MessageBoxTextArea.Value = {'Tracer'; '---------'; 'Version: 0.1.22726Alpha'; 'Author: Yao Zhang, Zhou''s Lab, CCME, PKU'; 'Original version: from Zezhou Li, Zhou''s Lab'};
            app.TabGroup = uitabgroup(app.GridLayout);
            app.BasicTab = uitab(app.TabGroup);
            app.BasicTab.Tooltip = {'Basic settings'};
            app.BasicTab.Title = 'Basic';
            app.RotationsettingsPanel = uipanel(app.BasicTab);
            app.RotationsettingsPanel.Title = 'Rotation settings';
            app.SliderLabel = uilabel(app.RotationsettingsPanel);
            app.SliderLabel.Text = 'φ';
            app.Slider_Phi = uislider(app.RotationsettingsPanel);
            app.Slider_Phi.Limits = [-180 180];
            app.Slider_Phi.MajorTicks = [-180 -90 0 90 180];
            app.Slider_Phi.ValueChangedFcn = createCallbackFcn(app, @Slider_PhiValueChanged, true);
            app.Slider_Phi.ValueChangingFcn = createCallbackFcn(app, @Slider_PhiValueChanging, true);
            app.Slider_Phi.MinorTicks = [-180 -170 -160 -150 -140 -130 -120 -110 -100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.Spinner_Phi = uispinner(app.RotationsettingsPanel);
            app.Spinner_Phi.Step = 0.5;
            app.Spinner_Phi.ValueChangingFcn = createCallbackFcn(app, @Spinner_PhiValueChanging, true);
            app.Spinner_Phi.Limits = [-180 180];
            app.Spinner_Phi.ValueDisplayFormat = '%.1f';
            app.Spinner_Phi.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhiValueChanged, true);
            app.Label = uilabel(app.RotationsettingsPanel);
            app.Label.Text = 'θ';
            app.Slider_Theta = uislider(app.RotationsettingsPanel);
            app.Slider_Theta.Limits = [-180 180];
            app.Slider_Theta.MajorTicks = [-180 -90 0 90 180];
            app.Slider_Theta.ValueChangedFcn = createCallbackFcn(app, @Slider_ThetaValueChanged, true);
            app.Slider_Theta.ValueChangingFcn = createCallbackFcn(app, @Slider_ThetaValueChanging, true);
            app.Slider_Theta.MinorTicks = [-180 -170 -160 -150 -140 -130 -120 -110 -100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.Spinner_Theta = uispinner(app.RotationsettingsPanel);
            app.Spinner_Theta.Step = 0.5;
            app.Spinner_Theta.ValueChangingFcn = createCallbackFcn(app, @Spinner_ThetaValueChanging, true);
            app.Spinner_Theta.Limits = [-180 180];
            app.Spinner_Theta.ValueDisplayFormat = '%.1f';
            app.Spinner_Theta.ValueChangedFcn = createCallbackFcn(app, @Spinner_ThetaValueChanged, true);
            app.Label_2 = uilabel(app.RotationsettingsPanel);
            app.Label_2.Text = 'ψ';
            app.Slider_Psi = uislider(app.RotationsettingsPanel);
            app.Slider_Psi.Limits = [-180 180];
            app.Slider_Psi.MajorTicks = [-180 -90 0 90 180];
            app.Slider_Psi.ValueChangedFcn = createCallbackFcn(app, @Slider_PsiValueChanged, true);
            app.Slider_Psi.ValueChangingFcn = createCallbackFcn(app, @Slider_PsiValueChanging, true);
            app.Slider_Psi.MinorTicks = [-180 -170 -160 -150 -140 -130 -120 -110 -100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.Spinner_Psi = uispinner(app.RotationsettingsPanel);
            app.Spinner_Psi.Step = 0.5;
            app.Spinner_Psi.ValueChangingFcn = createCallbackFcn(app, @Spinner_PsiValueChanging, true);
            app.Spinner_Psi.Limits = [-180 180];
            app.Spinner_Psi.ValueDisplayFormat = '%.1f';
            app.Spinner_Psi.ValueChangedFcn = createCallbackFcn(app, @Spinner_PsiValueChanged, true);
            app.ChangestepSpinner_2Label = uilabel(app.RotationsettingsPanel);
            app.ChangestepSpinner_2Label.Text = 'Change step';
            app.ChangestepSpinner_2 = uispinner(app.RotationsettingsPanel);
            app.ChangestepSpinner_2.Limits = [0 180];
            app.ChangestepSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ChangestepSpinner_2ValueChanged, true);
            app.ChangestepSpinner_2.Value = 0.5;
            app.ApplyrotationButton = uibutton(app.RotationsettingsPanel, 'push');
            app.ApplyrotationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyrotationButtonPushed, true);
            app.ApplyrotationButton.Text = 'Apply rotation';
            app.TracePanel = uipanel(app.BasicTab);
            app.TracePanel.Title = 'Trace';
            app.ShowatomButton = uibutton(app.TracePanel, 'state');
            app.ShowatomButton.ValueChangedFcn = createCallbackFcn(app, @ShowatomButtonValueChanged, true);
            app.ShowatomButton.Text = 'Show atom';
            app.ShowatomButton.Value = true;
            app.AddButton = uibutton(app.TracePanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Text = 'Add';
            app.StarttracingButton = uibutton(app.TracePanel, 'state');
            app.StarttracingButton.ValueChangedFcn = createCallbackFcn(app, @StarttracingButtonValueChanged, true);
            app.StarttracingButton.Text = 'Start tracing';
            app.DeleteRestoreButton = uibutton(app.TracePanel, 'push');
            app.DeleteRestoreButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRestoreButtonPushed, true);
            app.DeleteRestoreButton.Enable = 'off';
            app.DeleteRestoreButton.Text = 'Delete/Restore';
            app.RedoButton = uibutton(app.TracePanel, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Enable = 'off';
            app.RedoButton.Text = 'Redo';
            app.UndoButton = uibutton(app.TracePanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Enable = 'off';
            app.UndoButton.Text = 'Undo';
            app.SlicecontrolPanel = uipanel(app.BasicTab);
            app.SlicecontrolPanel.Title = ' Slice control';
            app.LayerfromEditFieldLabel = uilabel(app.SlicecontrolPanel);
            app.LayerfromEditFieldLabel.Text = 'Layer from';
            app.LayerFromEditField = uieditfield(app.SlicecontrolPanel, 'numeric');
            app.LayerFromEditField.ValueDisplayFormat = '%d';
            app.LayerFromEditField.ValueChangedFcn = createCallbackFcn(app, @LayerFromEditFieldValueChanged, true);
            app.toEditFieldLabel = uilabel(app.SlicecontrolPanel);
            app.toEditFieldLabel.Text = 'to';
            app.LayerToEditField = uieditfield(app.SlicecontrolPanel, 'numeric');
            app.LayerToEditField.ValueDisplayFormat = '%d';
            app.LayerToEditField.ValueChangedFcn = createCallbackFcn(app, @LayerToEditFieldValueChanged, true);
            app.LayerupButton = uibutton(app.SlicecontrolPanel, 'push');
            app.LayerupButton.ButtonPushedFcn = createCallbackFcn(app, @LayerupButtonPushed, true);
            app.LayerupButton.Text = 'Layer up';
            app.LayerdownButton = uibutton(app.SlicecontrolPanel, 'push');
            app.LayerdownButton.ButtonPushedFcn = createCallbackFcn(app, @LayerdownButtonPushed, true);
            app.LayerdownButton.Text = 'Layer down';
            app.LayerresetButton = uibutton(app.SlicecontrolPanel, 'push');
            app.LayerresetButton.Text = 'Layer reset';
            app.ChangestepSpinnerLabel = uilabel(app.SlicecontrolPanel);
            app.ChangestepSpinnerLabel.Text = 'Change step';
            app.ChangestepSpinner = uispinner(app.SlicecontrolPanel);
            app.ChangestepSpinner.Limits = [1 Inf];
            app.ChangestepSpinner.RoundFractionalValues = 'on';
            app.ChangestepSpinner.Value = 1;
            app.ThicknessSpinnerLabel = uilabel(app.SlicecontrolPanel);
            app.ThicknessSpinnerLabel.Text = 'Thickness';
            app.ThicknessSpinner = uispinner(app.SlicecontrolPanel);
            app.FigureinfoPanel = uipanel(app.BasicTab);
            app.FigureinfoPanel.Title = 'Figure info';
            app.ColormapDropDownLabel = uilabel(app.FigureinfoPanel);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.FigureinfoPanel);
            app.ColormapDropDown.Items = {'gray', 'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'bone', 'copper', 'pink', 'jet', 'lines', 'colorcube', 'prism', 'flag', 'white'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'gray';
            app.ViewdirectionDropDownLabel = uilabel(app.FigureinfoPanel);
            app.ViewdirectionDropDownLabel.Text = 'View direction';
            app.ViewdirectionDropDown = uidropdown(app.FigureinfoPanel);
            app.ViewdirectionDropDown.Items = {'Z', 'Y', 'X'};
            app.ViewdirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewdirectionDropDownValueChanged, true);
            app.ViewdirectionDropDown.Value = 'Z';
            app.DrawmaskButton = uibutton(app.FigureinfoPanel, 'push');
            app.DrawmaskButton.ButtonPushedFcn = createCallbackFcn(app, @DrawmaskButtonPushed, true);
            app.DrawmaskButton.Text = 'Draw mask';
            app.MasktypeDropDownLabel = uilabel(app.FigureinfoPanel);
            app.MasktypeDropDownLabel.Text = 'Mask type';
            app.MasktypeDropDown = uidropdown(app.FigureinfoPanel);
            app.MasktypeDropDown.Items = {'Rectangle'};
            app.MasktypeDropDown.Value = 'Rectangle';
            app.RectanglerangeEditFieldLabel = uilabel(app.FigureinfoPanel);
            app.RectanglerangeEditFieldLabel.Text = 'Rectangle range';
            app.RectanglerangeYEditField = uieditfield(app.FigureinfoPanel, 'numeric');
            app.Label_3 = uilabel(app.FigureinfoPanel);
            app.Label_3.Text = '-';
            app.RectanglerangeXEditField = uieditfield(app.FigureinfoPanel, 'numeric');
            app.DrawButton = uibutton(app.FigureinfoPanel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.RectanglerangeYEditField_2 = uieditfield(app.FigureinfoPanel, 'numeric');
            app.RectanglerangeXEditField_2 = uieditfield(app.FigureinfoPanel, 'numeric');
            app.DisplaymodeDropDownLabel = uilabel(app.FigureinfoPanel);
            app.DisplaymodeDropDownLabel.Text = 'Display mode';
            app.DisplaymodeDropDown = uidropdown(app.FigureinfoPanel);
            app.DisplaymodeDropDown.Items = {'Isosurface', 'Projection'};
            app.DisplaymodeDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplaymodeDropDownValueChanged, true);
            app.DisplaymodeDropDown.Enable = 'off';
            app.DisplaymodeDropDown.Value = 'Projection';
            app.Slider_Isovalue = uislider(app.FigureinfoPanel);
            app.Slider_Isovalue.Limits = [0 1];
            app.Slider_Isovalue.MajorTicks = [0 0.333333333333333 0.666666666666667 1];
            app.Slider_Isovalue.MajorTickLabels = {'0', '1/3', '2/3', 'MAX'};
            app.Slider_Isovalue.ValueChangedFcn = createCallbackFcn(app, @Slider_IsovalueValueChanged, true);
            app.Slider_Isovalue.Enable = 'off';
            app.Slider_Isovalue.Value = 0.5;
            app.LightOnButton = uibutton(app.FigureinfoPanel, 'push');
            app.LightOnButton.ButtonPushedFcn = createCallbackFcn(app, @LightOnButtonPushed, true);
            app.LightOnButton.Enable = 'off';
            app.LightOnButton.Text = 'Light On';
            app.DispTab = uitab(app.TabGroup);
            app.DispTab.Tooltip = {'Display settings'};
            app.DispTab.Title = 'Disp';
            app.IsosurfaceinfoPanel = uipanel(app.DispTab);
            app.IsosurfaceinfoPanel.Title = 'Isosurface info';
            app.AlphaSliderLabel = uilabel(app.IsosurfaceinfoPanel);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.IsoAlphaSlider = uislider(app.IsosurfaceinfoPanel);
            app.IsoAlphaSlider.Limits = [0 1];
            app.IsoAlphaSlider.ValueChangedFcn = createCallbackFcn(app, @IsoAlphaSliderValueChanged, true);
            app.IsoAlphaSlider.Enable = 'off';
            app.IsoAlphaSlider.Value = 0.5;
            app.IsoColorButton = uibutton(app.IsosurfaceinfoPanel, 'push');
            app.IsoColorButton.ButtonPushedFcn = createCallbackFcn(app, @IsoColorButtonPushed, true);
            app.IsoColorButton.Enable = 'off';
            app.IsoColorButton.Text = 'Surface color';
            app.IsoColorDisplayLabel = uilabel(app.IsosurfaceinfoPanel);
            app.IsoColorDisplayLabel.Text = 'Preview';
            app.ZoominPanel = uipanel(app.DispTab);
            app.ZoominPanel.Title = 'Zoom in';
            app.DrawZoomROIButton = uibutton(app.ZoominPanel, 'push');
            app.DrawZoomROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawZoomROIButtonPushed, true);
            app.DrawZoomROIButton.Text = 'Draw Zoom ROI';
            app.ResetZoomROIButton = uibutton(app.ZoominPanel, 'push');
            app.ResetZoomROIButton.ButtonPushedFcn = createCallbackFcn(app, @ResetZoomROIButtonPushed, true);
            app.ResetZoomROIButton.Text = 'Reset Zoom ROI';
            app.DrawatomPanel = uipanel(app.DispTab);
            app.DrawatomPanel.Title = 'Draw atom';
            app.AtomListBox = uilistbox(app.DrawatomPanel);
            app.AtomListBox.Items = {};
            app.AtomListBox.Enable = 'off';
            app.AtomListBox.Value = {};
            app.AtomColorButton = uibutton(app.DrawatomPanel, 'push');
            app.AtomColorButton.ButtonPushedFcn = createCallbackFcn(app, @AtomColorButtonPushed, true);
            app.AtomColorButton.Enable = 'off';
            app.AtomColorButton.Text = 'Select color';
            app.AtomsizeEditFieldLabel = uilabel(app.DrawatomPanel);
            app.AtomsizeEditFieldLabel.Text = 'Atom size';
            app.AtomsizeEditField = uieditfield(app.DrawatomPanel, 'numeric');
            app.AtomsizeEditField.ValueChangedFcn = createCallbackFcn(app, @AtomsizeEditFieldValueChanged, true);
            app.AtomsizeEditField.Enable = 'off';
            app.AtomsizeEditField.Value = 20;
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'Tracerlogo.png';
            app.SideView2TabGroup = uitabgroup(app.GridLayout);
            app.Sideview2Tab = uitab(app.SideView2TabGroup);
            app.Sideview2Tab.Title = 'Sideview 2';
            app.GridLayout3 = uigridlayout(app.Sideview2Tab);
            app.SideView2Axes = uiaxes(app.GridLayout3);
            title(app.SideView2Axes, 'Title')
            xlabel(app.SideView2Axes, 'X')
            ylabel(app.SideView2Axes, 'Y')
            zlabel(app.SideView2Axes, 'Z')
            app.FinetraceTab_2 = uitab(app.SideView2TabGroup);
            app.FinetraceTab_2.Title = 'Fine trace';
            app.FineTrace2_down_Axes = uiaxes(app.FinetraceTab_2);
            title(app.FineTrace2_down_Axes, 'Down layer')
            xlabel(app.FineTrace2_down_Axes, 'X')
            ylabel(app.FineTrace2_down_Axes, 'Y')
            app.FineTrace2_down_Axes.ZTick = 0;
            app.FineTrace2_down_Axes.Visible = 'off';
            app.FineTrace2_middle_Axes = uiaxes(app.FinetraceTab_2);
            title(app.FineTrace2_middle_Axes, 'Middle layer')
            xlabel(app.FineTrace2_middle_Axes, 'X')
            ylabel(app.FineTrace2_middle_Axes, 'Y')
            app.FineTrace2_middle_Axes.ZTick = 0;
            app.FineTrace2_middle_Axes.Visible = 'off';
            app.FineTrace2_up_Axes = uiaxes(app.FinetraceTab_2);
            title(app.FineTrace2_up_Axes, 'Upper layer')
            xlabel(app.FineTrace2_up_Axes, 'X')
            ylabel(app.FineTrace2_up_Axes, 'Y')
            app.FineTrace2_up_Axes.ZTick = 0;
            app.FineTrace2_up_Axes.Visible = 'off';
            app.SideView1TabGroup = uitabgroup(app.GridLayout);
            app.Sideview1Tab = uitab(app.SideView1TabGroup);
            app.Sideview1Tab.Title = 'Sideview 1';
            app.GridLayout2 = uigridlayout(app.Sideview1Tab);
            app.SideView1Axes = uiaxes(app.GridLayout2);
            title(app.SideView1Axes, 'Title')
            xlabel(app.SideView1Axes, 'X')
            ylabel(app.SideView1Axes, 'Y')
            zlabel(app.SideView1Axes, 'Z')
            app.FinetraceTab_1 = uitab(app.SideView1TabGroup);
            app.FinetraceTab_1.Title = 'Fine trace';
            app.FineTrace1_down_Axes = uiaxes(app.FinetraceTab_1);
            title(app.FineTrace1_down_Axes, 'Down layer')
            xlabel(app.FineTrace1_down_Axes, 'X')
            ylabel(app.FineTrace1_down_Axes, 'Y')
            app.FineTrace1_down_Axes.ZTick = 0;
            app.FineTrace1_down_Axes.Visible = 'off';
            app.FineTrace1_middle_Axes = uiaxes(app.FinetraceTab_1);
            title(app.FineTrace1_middle_Axes, 'Middle layer')
            xlabel(app.FineTrace1_middle_Axes, 'X')
            ylabel(app.FineTrace1_middle_Axes, 'Y')
            app.FineTrace1_middle_Axes.ZTick = 0;
            app.FineTrace1_middle_Axes.Visible = 'off';
            app.FineTrace1_up_Axes = uiaxes(app.FinetraceTab_1);
            title(app.FineTrace1_up_Axes, 'Upper layer')
            xlabel(app.FineTrace1_up_Axes, 'X')
            ylabel(app.FineTrace1_up_Axes, 'Y')
            app.FineTrace1_up_Axes.ZTick = 0;
            app.FineTrace1_up_Axes.Visible = 'off';
            app.ExpandYourHorizonLabel = uilabel(app.GridLayout);
            app.ExpandYourHorizonLabel.Text = {'Expand'; 'Your'; 'Horizon'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tracer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @tracerStartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end